classdef analysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackingAnalysisWizardUIFigure  matlab.ui.Figure
        LoaddisplacementsButton         matlab.ui.control.Button
        RemovepointsCheckBox            matlab.ui.control.CheckBox
        IgnoretransformCheckBox         matlab.ui.control.CheckBox
        MakevideoCheckBox               matlab.ui.control.CheckBox
        MakeplotsCheckBox               matlab.ui.control.CheckBox
        TrackedParticleAnalyzerLabel    matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        PlottedparticledataSwitchLabel  matlab.ui.control.Label
        PlottedparticledataSwitch       matlab.ui.control.Switch
        VideoparticledataSwitchLabel    matlab.ui.control.Label
        VideoparticledataSwitch         matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        PointtoremoveSpinnerLabel       matlab.ui.control.Label
        PointtoremoveSpinner            matlab.ui.control.Spinner
        SelectpointButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ColourScaleSwitchLabel          matlab.ui.control.Label
        ColourScaleSwitch               matlab.ui.control.Switch
        ArrowScaleSwitchLabel           matlab.ui.control.Label
        ArrowScaleSwitch                matlab.ui.control.Switch
        DilationfactorEditFieldLabel    matlab.ui.control.Label
        DilationfactorEditField         matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        EnsureimagestackisinthesamefolderbeforeloadingdataLabel  matlab.ui.control.Label
        FrameviewSpinnerLabel           matlab.ui.control.Label
        FrameviewSpinner                matlab.ui.control.Spinner
        ShowPointsButton                matlab.ui.control.Button
        RecoverdeletedpointsCheckBox    matlab.ui.control.CheckBox
        ArrowplacementvideoButtonGroup  matlab.ui.container.ButtonGroup
        HeadButton                      matlab.ui.control.RadioButton
        MidpointButton                  matlab.ui.control.RadioButton
        TailButton                      matlab.ui.control.RadioButton
        WorkinbackgroundCheckBox        matlab.ui.control.CheckBox
        FramethresholdEditFieldLabel    matlab.ui.control.Label
        FramethresholdEditField         matlab.ui.control.NumericEditField
        RemovenoiseCheckBox             matlab.ui.control.CheckBox
        DeviationfrommedianEditFieldLabel  matlab.ui.control.Label
        DeviationfrommedianEditField    matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        StepsizeEditFieldLabel          matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        FixframeCheckBox                matlab.ui.control.CheckBox
        FrameRateEditFieldLabel         matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        a;%object to store properties of stack
        nfile;%number of frames used
        startFrame;
        filename;
        path;
        dispLoaded = false;
        dispDataName;
        dispDataPath;
        pointsToRemove = [];
        frameSpinnerStart;
        propPart = [];
        propFiltPart =[];
        isMosaic = false;
    end
    
    methods (Access = private)
        
        function printImage(app, frame)
            %{
            prints the image to the preview pane. Frame shown
            corresponds to filenum argument. startFrame = first frame, imgA = displayed img,
            I is the fcn displaying imgA on the specified axes.
            %}
            
            imgA = imread([app.dispDataPath, app.a(frame).name]);%read the first image from the path where the disp data reside
            app.UIAxes.Visible = true; %make axes appear
            I = imshow(imgA,'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)]; % resizes image to fit frame
            app.UIAxes.YLim = [0 I.YData(2)];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
        end

        % Button pushed function: LoaddisplacementsButton
        function LoaddisplacementsButtonPushed(app, event)
            oldDisp = app.dispDataName; oldPath = app.dispDataPath; % saves old path
            [app.dispDataName,app.dispDataPath] =uigetfile('*.mat;*.csv');%get matlab displacements file
            if app.dispDataName == 0 %no file is loaded
                uiconfirm(app.TrackingAnalysisWizardUIFigure, 'No displacements loaded.', 'No displacements', 'Options', {'OK'}, 'Icon', 'error');
                app.TrackingAnalysisWizardUIFigure.Visible = false;
                app.TrackingAnalysisWizardUIFigure.Visible = true;
                app.dispDataName = oldDisp; 
                app.dispDataPath = oldPath;
                return;
            end
            
            if ~strncmp(fliplr(app.dispDataName), 'tam.', 4) && ~strncmp(fliplr(app.dispDataName), 'vsc.', 4) %wrong file type loaded
                uiconfirm(app.TrackingAnalysisWizardUIFigure, 'The file was not a .mat or .csv file.  Select a MATLAB formatted data file generated in the IRL Tracking App or a MosaicSUITE formatted .csv file.', 'Wrong file type', 'Options', {'OK'}, 'Icon', 'error');
                app.TrackingAnalysisWizardUIFigure.Visible = false;
                app.TrackingAnalysisWizardUIFigure.Visible = true;
                app.dispDataName = oldDisp;
                app.dispDataPath = oldPath;
                return;
            end
            if any(size(dir([app.dispDataPath '/*.tif' ]),1)) == false %disp data is not with an img stack
                uiconfirm(app.TrackingAnalysisWizardUIFigure, 'Please make sure your image stack is in the same folder as the displacement data.', 'No images', 'Options', {'OK'}, 'Icon', 'error');
                app.TrackingAnalysisWizardUIFigure.Visible = false;
                app.TrackingAnalysisWizardUIFigure.Visible = true;
                app.dispDataName = oldDisp;
                app.dispDataPath = oldPath;
                return;
            end
            if strncmp(fliplr(app.dispDataName), 'vsc.', 4)
                app.isMosaic = true;
                app.VideoparticledataSwitch.Enable = false;
                app.PlottedparticledataSwitch.Enable = false;
                app.VideoparticledataSwitchLabel.Enable = false;
                app.PlottedparticledataSwitchLabel.Enable = false;
                app.IgnoretransformCheckBox.Enable = false;
                app.IgnoretransformCheckBox.Value = false;
                tempArray = readtable([app.dispDataPath, app.dispDataName]);%works with pre-2019 matlab. more importantly, if there are headings, we still get the same data
                tempArray = table2array(tempArray);
                if tempArray(1,1) == (tempArray(2,1)-1) && tempArray(2,1) == (tempArray(3,1)-1)
                    tempArray = tempArray(:,2:5);
                else
                    tempArray = tempArray(:,1:4); %remove columns we don't use
                end
                maxframe = max(tempArray,[],1);
                maxframe = maxframe(1,2);
                app.FramethresholdEditField.Limits = [1,maxframe];
                app.FramethresholdEditFieldLabel.Enable = true;
                app.FramethresholdEditField.Enable = true;
            else
                app.isMosaic = false;
                app.VideoparticledataSwitch.Enable = true;
                app.PlottedparticledataSwitch.Enable = true;
                app.VideoparticledataSwitchLabel.Enable = true;
                app.PlottedparticledataSwitchLabel.Enable = true;
                app.IgnoretransformCheckBox.Enable = true;
                app.FramethresholdEditFieldLabel.Enable = false;
                app.FramethresholdEditField.Enable = false;
            end
            app.propPart = [];
            app.propFiltPart = [];
            app.a = dir([app.dispDataPath '*.tif']); %defining a struct that holds the img stack and reindexing it
            [~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
            app.a = app.a(reindex);
            app.dispLoaded = true; %Activates all the features of the analysis app now that disp data is loaded
            app.ExecuteButton.Enable = true;
            app.RemovepointsCheckBox.Enable = true;
            app.UIAxes.Visible = true;
            app.printImage(1);
            app.FrameviewSpinner.Limits = [1, max(size(app.a))]; %sets limit to total frames
            app.FrameviewSpinner.Enable = true;
            app.FrameviewSpinnerLabel.Enable = true;
            app.ShowPointsButton.Visible = true;
            app.ShowPointsButton.Enable = true;
            app.TrackingAnalysisWizardUIFigure.Visible = false;
            app.TrackingAnalysisWizardUIFigure.Visible = true;
%             app.StepsizeEditField.Limits = [1, size(particle_bk,3)/2];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
            
            app.PointtoremoveSpinner.Value = 1; %reset points to remove spinner
            app.ExecuteButton.Enable = false;
            %confirmation dlg box, if yes the analysis runs
            answer = uiconfirm(app.TrackingAnalysisWizardUIFigure,'Run analysis with the selected options?','Confirmation','Options', {'Yes','No'}, 'DefaultOption', 2);


            if strcmp(answer,'Yes')
                
                %if the file is a .mat then load the data saved from the particle tracker
                
                if strncmp(fliplr(app.dispDataName), 'tam.', 4)
                    load ([app.dispDataPath, app.dispDataName], 'filt_particle', 'particle_bk', 'pathvar', 'filenamevar');
                    if ~exist('filt_particle', 'var') || ~exist('particle_bk', 'var') || ~exist('pathvar', 'var') || ~exist('filenamevar', 'var')
                        
                        if app.StepsizeEditField.Value > size(particle_bk,1)/2
                            
                           uiconfirm(app.TrackingAnalysisWizardUIFigure, 'Enter a smaller step value.', 'Large step size', 'Options', {'OK'}, 'Icon', 'error') 
                           return
                            
                        end
                        
                        uiconfirm(app.TrackingAnalysisWizardUIFigure, 'Please make sure to use a displacements file generated in the IRL Particle Tracking Wizard', 'Couldn''t load variables', 'Options', {'OK'}, 'Icon', 'error');
                        app.TrackingAnalysisWizardUIFigure.Visible = false;
                        app.TrackingAnalysisWizardUIFigure.Visible = true;
                        app.ExecuteButton.Enable = true;
                        return;
                    end
                    
                    
                else %if the data is mosaic data convert the .csv to a 3d array we can use later
                    app.isMosaic = true;
                    
                    %                    tempArray = readmatrix([app.dispDataPath, app.dispDataName]); %converts .csv to a 2d matrix
                    
                    tempArray = readtable([app.dispDataPath, app.dispDataName]);%works with pre-2019 matlab. more importantly, if there are headings, we still get the same data
                    %                    if strcmp('Trajectory', tempArray.Properties.DimensionNames(1))
                    tempArray = table2array(tempArray);
                    %                    end
                    if tempArray(1,1) == (tempArray(2,1)-1) && tempArray(2,1) == (tempArray(3,1)-1)
                        tempArray = tempArray(:,2:5);
                    else
                        tempArray = tempArray(:,1:4); %remove columns we don't use
                    end
                    particleNumber = max(tempArray,[],1); %finds maximum number in the first column (which is the amount of particles)
                    particleNumber = particleNumber(1,1);
                    frameNumber = max(tempArray,[],1); %Same as above but finds the frame # from the second column
                    frameNumber = frameNumber(1,2) + 1;
                    particle_bk = NaN(frameNumber,2,particleNumber); %fills particle_bk with nans so our code can interpret the holes in the mosaic data
                    
                    g = 1;
                    for i = 1:particleNumber %loops through all the mosaic data and puts it into the 3d array defined above
                        while g < size(tempArray,1) && (tempArray(g+1,2) > tempArray(g,2))
                            particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                            particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                            g = g + 1;
                        end
                        particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                        particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                        if g < size(tempArray,1)
                            g = g + 1;
                        end
                    end
                    filt_particle = particle_bk;
                end
                
                
                app.path = app.dispDataPath; %defines some important properties from the img stack
                if ~app.isMosaic
                    app.filename = filenamevar;%filenamevar was saved in tracking app in case it's not 1
                else
                    app.filename = app.a(1).name;
                end
                app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
                app.nfile = max(size(app.a)) - app.startFrame + 1;    %% take the number of files and store it in nfile
                
                if isempty(app.propPart) == false && app.RecoverdeletedpointsCheckBox.Value == false %load previous particle data from properties
                    particle_bk = app.propPart;
                    filt_particle = app.propFiltPart;
                else %recover original data
                    app.propPart = particle_bk;
                    app.propFiltPart = filt_particle;
                end
                
                
                if app.RemovepointsCheckBox.Value == true %removing particles
                    for i = 1:size(app.pointsToRemove,2)
                        particle_bk(1,1,app.pointsToRemove(1,i)) = Inf; %marks a particle for deletion by placing and Inf
                        filt_particle(1,1,app.pointsToRemove(1,i)) = Inf;
                    end
                    
                    t = 1;
                    while t <= size(particle_bk,3) %finds any particles marked for deletion and deletes them
                        if particle_bk(1,1,t) == Inf
                            particle_bk(:,:,t) = [];
                            filt_particle(:,:,t) = [];
                            t = t-1;
                        end
                        t = t+1;
                    end
                    app.PointtoremoveSpinner.Enable = false; %resets everything to do with point removal and saves the new particle arrays to a property
                    app.PointtoremoveSpinner.Visible = false;
                    app.PointtoremoveSpinnerLabel.Visible = false;
                    app.PointtoremoveSpinnerLabel.Enable = false;
                    app.SelectpointButton.Visible = false;
                    app.SelectpointButton.Enable = false;
                    app.pointsToRemove = [];
                    app.RemovepointsCheckBox.Value = false;
                    app.propPart = particle_bk;
                    app.propFiltPart = filt_particle;
                end
                
                frameLim = app.FramethresholdEditField.Value;
                
                if frameLim ~= 1
                    numNan = frameNumber - frameLim;
                    i = 1;
                    while i <= size(particle_bk,3)
                        if sum(isnan(particle_bk(:,1,i))) > numNan
                            particle_bk(:,:,i) = [];
                            filt_particle(:,:,i) = [];
                            i = i-1;
                        end
                        i = i+1;
                    end
                    app.propPart = particle_bk;
                    app.propFiltPart = filt_particle;
                end


                particle = particle_bk;
                
                
                [tformFile, ~] = sprintf('%stransform.mat',convertCharsToStrings(app.path(1:end-2)));
                
                if exist(tformFile, 'file') && app.IgnoretransformCheckBox.Value == false %loads a transform file or ignores it
                    load(tformFile, '-mat', HsRts);
                else
                    HsRts = zeros(3,3,size(particle,1));
                    for i =1:(size(particle, 1))
                        HsRts(:,:,i) = [1,0,0;0,1,0;0,0,1];
                    end
                end
                
                if exist('isNcorr', 'var')
                        
                       for i = 2 : size(particle,1)
                           
                          for u = 1 : size(particle,3)
                             if particle(i,1,u) == particle(1,1,u) || particle(i,2,u) == particle(1,2,u)
                              
                                 particle(i,1,u) = NaN;
                                 particle(i,2,u) = NaN;
                                 
                                 filt_particle(i,1,u) = NaN;
                                 filt_particle(i,2,u) = NaN;
                                 
                             end
                              
                          end                           
                           
                      end                              
               end
                
%                 if app.StepsizeEditField.Value ~= 1
%                     
%                     
%                     
%                  
%                     h = 1;
%                     for i = 1 :stepSize : size(particle,1)
%                        
%                         newParticle(h,:,:) = particle(i,:,:);
%                         new_filt_particle(h,:,:) = filt_particle(i,:,:);
%                         
%      
%                     end
%                     
%                end
                    
                    
                stepSize = app.StepsizeEditField.Value;
                %original location
                particleNoNan = particle;
                t = 1;
                while t <= size(particleNoNan,3) %creates a particle without nans in frame 1 (for mosaic data)
                    if isnan(particleNoNan(1,1,t))
                        particleNoNan(:,:,t) = [];
                        t = t-1;
                    end
                    t = t+1;
                end
                originalPts2_x = zeros(1,size(particleNoNan,3)); %creates arrays with original position information
                originalPts2_y = zeros(1,size(particleNoNan,3));
                for i = 1:size(particleNoNan,3)
                    originalPts2_x(1,i) = particleNoNan (1,1,i);
                    originalPts2_y(1,i) = particleNoNan (1,2,i);
                end
                trans_orig_steps = [];
                for i = 1:length(HsRts)
                    tform = affine2d(HsRts(:,:,i));
                    [U,V] = transformPointsInverse(tform, originalPts2_x, originalPts2_y);
                    stepTransPt = [];
                    for k = 1:length(U)
                        stepTransPt = [stepTransPt, U(k), V(k)];
                    end
                    trans_orig_steps = [trans_orig_steps; stepTransPt]; %creates a larger array with original position information
                end
                if app.MakeplotsCheckBox.Value == true || app.MakevideoCheckBox.Value == true %choose location to save video / plots
                    [saveFile, savePath] = uiputfile('*');
                    directory = [savePath, saveFile, '\'];
                    if savePath == 0 
                        uiconfirm(app.TrackingAnalysisWizardUIFigure,'The file save was canceled.', 'Data not saved','Options', {'OK'}, 'Icon', 'warning');
                        app.ExecuteButton.Enable = true;
                        return;
                    end
                    if ~exist(directory,'dir')
                        mkdir(directory)
                    end
                end
                if app.MakevideoCheckBox.Value == true
                    clr = 'rgbywp'; %we can remove this?
                    t = app.startFrame;
                    
                    
                    v = VideoWriter(sprintf('%sstepQuiver.avi',directory),'Motion JPEG AVI'); %NAME OF VIDEO FILE
                    v.FrameRate = app.FrameRateEditField.Value;
                    open(v);
                    
                    Rs = nan(app.nfile - 1,size(particle,3));
                    if strcmp(app.VideoparticledataSwitch.Value, 'Smoothed') %defines a particle for the video based on smoothed or true data
                        vidPart = filt_particle;
                    else
                        vidPart = particle;
                    end
                    
                    if app.isMosaic == true && ~strcmp(app.ColourScaleSwitch.Value,'FBF')
                        vidPart = particleNoNan;
                    end
                    
                    if strcmp(app.ColourScaleSwitch.Value,'FBF') %creates an array of either the total or frame by frame displacment lengths (Rs)
                        for i = 1:stepSize:size(vidPart, 1)-1
                            h = 1;
                            for u = 1:size(vidPart, 3)
                                Rs(i,u) =  sqrt((vidPart(i+1,1,u) - vidPart(i,1,u))^2+(vidPart(i+1,2,u) - vidPart(i,2,u))^2);
                                h = h+2;
                            end
                        end
                    else
                        for i = 1:stepSize:size(vidPart, 1)-1
                            h = 1;
                            for u = 1:size(vidPart, 3)
                                Rs(i,u) =  sqrt((vidPart(i+1,1,u) - trans_orig_steps(i+1,h))^2+(vidPart(i+1,2,u) - trans_orig_steps(i+1,h+1))^2);
                                h = h+2;
                            end
                        end
                    end
                    
                    
                    if app.RemovenoiseCheckBox.Value == true
                        deviation = (app.DeviationfrommedianEditField.Value/100)+1;
                        medianRs = median(Rs,2);
                        for i = 1:size(medianRs,1)-1
                            for g = 1:size(vidPart,3)
                                if Rs(i,g) > medianRs(i,1)*deviation
                                    vidPart(i,1,g) = NaN;
                                    vidPart(i,2,g) = NaN;
                                end
                            end
                        end
                    end
                    
                    maxVector = nanmax(Rs,[],2); %creates a vector of the frame by frame max and min of Rs
                    minVector = nanmin(Rs,[],2);
                    totalMax = max(maxVector);
                    totalMin = min(minVector);
                    d = uiprogressdlg(app.TrackingAnalysisWizardUIFigure, 'Cancelable', 'on', 'Title','Studying trajectories.  Please wait...');
                    for i = 1 : stepSize : size(vidPart, 1)-1
                        if app.WorkinbackgroundCheckBox.Value == 1
                            trackPlot = figure('Visible', 'off', 'Color','k');
                        else
                            trackPlot = figure('Color','k');
                        end
                        d.Value = i/size(vidPart,1);
                        
                        if rem(round(d.Value*100, 1, 'decimal'), 1) == 0%always go to one decimal (when the percent is an int the lower line does not include the decimal)
                            d.Message = [num2str(round(d.Value*100, 1, 'decimal')), '.0% complete.'];
                        else
                            d.Message = [num2str(round(d.Value*100, 1, 'decimal')), '% complete.'];
                        end
                        
                        if d.CancelRequested
                            close(trackPlot);
                            close(d); %folder not deleted
                            app.ExecuteButton.Enable = true;
                            return;
                        end
                        if app.FixframeCheckBox.Value == false
                            fileName = sprintf('%s%s', app.path, app.a(i).name);% fileName = sprintf('%s%s', app.path, app.a(t).name);
                            
                            
                        else
                            fileName = sprintf('%s%s', app.path, app.a(1).name);
                        end
%                         t = t+1;
                        %                        set(trackPlot, 'Position', [0, 0, size(fileName)]);
                        imshow(fileName);
                        hold on;
                        h = 1;
                        %using the GoodArrow function to draw either frame
                        %by frame arrows, or overall displacment from start
                        %point arrows. With either frame by frame colour
                        %scaling or overall colour scaling
                        f = cell(size(vidPart,3),3); %array of handles for each particle's arrow
                        if app.HeadButton.Value == true
                            mode = 0;
                        elseif app.MidpointButton.Value == true
                            mode = 1;
                        else
                            mode = 2;
                        end
                        if strcmp(app.ColourScaleSwitch.Value,'FBF') && strcmp(app.ArrowScaleSwitch.Value, 'Total')%per frame colouring & overall scaling
                            for u = 1:size(vidPart,3)
                                [head1, head2, tail] = GoodArrow(trans_orig_steps(i,h),trans_orig_steps(i,h+1),vidPart(i+stepSize,1,u), vidPart(i+stepSize,2,u),ColScale(minVector(i),maxVector(i),Rs(i,u)),app.DilationfactorEditField.Value, mode);
                                hold on;
                                h = h+2;
                                if ~isobject(head1) || ~isobject(head2) || ~isobject(tail)
                                    delete(f{u,:}); % if theres a nan then stop showing the arrow for the particle
                                else% since nan was inserted where mosaic had trouble this accnts for that
                                    f{u,1} = get(head1);%if it is being tracked update the handle to the new arrow
                                    f{u,2} = get(head2);
                                    f{u,3} = get(tail);
                                end
                            end
                        elseif strcmp(app.ColourScaleSwitch.Value, 'FBF') && strcmp(app.ArrowScaleSwitch.Value, 'FBF')%all per frame
                            for u = 1:size(vidPart,3)
                                [head1, head2, tail] = GoodArrow(vidPart(i,1,u),vidPart(i,2,u),vidPart(i+1,1,u), vidPart(i+1,2,u),ColScale(minVector(i),maxVector(i),Rs(i,u)),app.DilationfactorEditField.Value, mode);
                                hold on;
                                if ~isobject(head1) || ~isobject(head2) || ~isobject(tail)
                                    delete(f{u,:}); % if theres a nan then stop showing the arrow for the particle
                                else% since nan was inserted where mosaic had trouble this accnts for that
                                    f{u,1} = get(head1);%if it is being tracked update the handle to the new arrow
                                    f{u,2} = get(head2);
                                    f{u,3} = get(tail);
                                end
                            end
                        elseif strcmp(app.ColourScaleSwitch.Value, 'Total') && strcmp(app.ArrowScaleSwitch.Value, 'FBF')%overall colouring and per frame scaling
                            for u = 1:size(vidPart, 3)
                                [head1, head2, tail] = GoodArrow(vidPart(i,1,u),vidPart(i,2,u),vidPart(i+1,1,u), vidPart(i+1,2,u),ColScale(totalMin,totalMax,Rs(i,u)),app.DilationfactorEditField.Value, mode);
                                hold on;
                                if ~isobject(head1) || ~isobject(head2) || ~isobject(tail)
                                    delete(f{u,:}); % if there's a nan then stop showing the arrow for the particle
                                else% since nan was inserted where mosaic had trouble this accnts for that
                                    f{u,1} = get(head1);%if it is being tracked update the handle to the new arrow
                                    f{u,2} = get(head2);
                                    f{u,3} = get(tail);
                                end
                            end
                        else
                            for u = 1:size(vidPart,3)
                                try
                                [head1, head2, tail] = GoodArrow(trans_orig_steps(i,h),trans_orig_steps(i,h+1),vidPart(i+1,1,u), vidPart(i+1,2,u),ColScale(totalMin,totalMax,Rs(i,u)),app.DilationfactorEditField.Value, mode);
                                
                                hold on;
                                catch
                                printf(['problem with point ',int2str(u),' on frame ',int2str(i)])
                                end
                                h = h+2;
                                if ~isobject(head1) || ~isobject(head2) || ~isobject(tail)
                                    delete(f{u,:}); % if theres a nan then stop showing the arrow for the particle
                                else% since nan was inserted where mosaic had trouble this accnts for that
                                    f{u,1} = get(head1);%if it is being tracked update the handles to the new arrow
                                    f{u,2} = get(head2);
                                    f{u,3} = get(tail);
                                end
                            end
                        end
                        %                    for u = 1:2:size(filt_steps, 2)
                        %                        Rs(i,(u+1)/2) =  sqrt((filt_steps(i,u) - trans_orig_steps(i,u))^2+(filt_steps(i,u+1) - trans_orig_steps(i,u+1))^2);
                        %                        GoodArrow(trans_orig_steps(i,u),trans_orig_steps(i,u+1),filt_steps(i,u), filt_steps(i,u+1),ColScale(0,66,Rs(i,((u+1)/2))));
                        %                        hold on;
                        %                    end
                        %                        [rows, columns, ~] = size(imread(fileName));
                        
                        %                        set(gca, 'Units', 'Normalized'); % First change to normalized units.
                        %                       set(gca,'OuterPosition', trackPlot.Position); % [xLeft, yBottom, width, height]
                        
                        
                        frame = getframe(trackPlot);
                        writeVideo(v,frame);
                        trackPlot.delete;
                    end
                    close(v);
                    
                    close(d);
                    
                end
                
                if app.MakeplotsCheckBox.Value == true
                    if strcmp(app.PlottedparticledataSwitch.Value, 'Smoothed') %defines a particle for the plots using either filtered or true data
                        plotPart = filt_particle;
                    else
                        plotPart = particle;
                    end
                    if app.isMosaic
                        app.nfile = app.nfile + 1;
                    end
                    plot_particle2d = zeros(app.nfile-1,size(plotPart,3)*2); %creates a 2d particle matrix for use in the plots
                    k = 1;
                    for i = 1:2:(size(particle,3))*2
                        plot_particle2d(:,i) = plotPart(:,1,k);
                        plot_particle2d(:,i+1) = plotPart(:,2,k);
                        k = k+1;
                    end
                    
                    
                    fig1 = figure('Visible', 'off');plot(plot_particle2d(:,1:2:end)); %creating the plots
                    title('X Displacement per Frame');
                    ylabel('Displacement (px)');
                    xlabel('Frame');
                    fig1.Visible = true;
                    figure(fig1);
                    
                    fig2 = figure('Visible', 'off');plot(plot_particle2d(:,2:2:end));
                    title('Y Displacement per Frame');
                    ylabel('Displacement (px)');
                    xlabel('Frame');
                    fig2.Visible = true;
                    figure(fig2);
                    
                    fig3 = figure('Visible', 'off'); plot(plot_particle2d(:,1:2:end),plot_particle2d(:,2:2:end));
                    title('X vs Y Displacement: Time History');
                    ylabel('Y Displacement (px)');
                    xlabel('X Displacement (px)');
                    axis equal;
                    fig3.Visible = true;
                    figure(fig3);
                    
                    fig4 = figure('Visible', 'off'); plot(nanmean((plot_particle2d(:,2:2:end)-plot_particle2d(1,2:2:end)), 2)) %does not work with mosaic yet
                    title('Mean Y Displacement per Frame');
                    ylabel('Mean Displacement (px)');
                    xlabel('Frame');
                    axis equal;
                    fig4.Visible = true;
                    figure(fig4);
                    
                    fig5 = figure('Visible', 'off'); plot(nanmean((plot_particle2d(:,1:2:end)-plot_particle2d(1,1:2:end)), 2))
                    title('Mean X Displacement per Frame');
                    ylabel('Mean Displacement (px)');
                    xlabel('Frame');
                    axis equal;
                    fig5.Visible = true;
                    figure(fig5);
                    
                    saveas(fig1,sprintf('%sXDisplacement.png',directory)); %saving plots
                    saveas(fig2,sprintf('%sYDisplacement.png',directory));
                    saveas(fig3,sprintf('%sXYTime.png',directory));
                    saveas(fig4,sprintf('%sAverageY.png',directory));
                    saveas(fig5,sprintf('%sAverageX.png',directory));
                    
                    %button that can close all the plots at once
                    app.TrackingAnalysisWizardUIFigure.Visible = false;
                    app.TrackingAnalysisWizardUIFigure.Visible = true;
                    button = uiconfirm(app.TrackingAnalysisWizardUIFigure, ['The analysis is complete.  Data saved to ', directory], 'Attention', 'Options', {'OK', 'Close all', 'Open folder & close all'}, 'DefaultOption', 2, 'icon', 'success');
                    if strcmp(button, 'Close all')
                        close all;
                    elseif strcmp(button, 'Open folder & close all')
                        close all;
                        if ispc
                            winopen(directory);
                        else
                            try
                                system(['open ' directory]);%cant test on mac--need to try on scott's os
                            catch
                                try
                                    system(['open ' directory, ' &'])
                                catch
                                    errordlg('This doesn''t work on mac yet...sorry!', 'Uh-oh');
                                end
                            end
                        end
                    end
                elseif app.MakevideoCheckBox.Value == true
                    app.TrackingAnalysisWizardUIFigure.Visible = false;
                    app.TrackingAnalysisWizardUIFigure.Visible = true;
                    button = uiconfirm(app.TrackingAnalysisWizardUIFigure, ['The analysis is complete.  Data saved to ', directory], 'Attention', 'Options', {'OK', 'Open folder'}, 'DefaultOption', 2, 'icon', 'success'); %alert user that analysis is complete
                    if strcmp(button, 'Open folder')
                        if ispc
                            winopen(directory);
                        else
                            try
                                system(['open ' pwd]);
                            catch
                                errordlg('This doesn''t work on mac yet...sorry!', 'Uh-oh');
                            end
                        end
                    end
                end
            end
            app.ExecuteButton.Enable = true;
            
        end

        % Value changed function: VideoparticledataSwitch
        function VideoparticledataSwitchValueChanged(app, event)
            if strcmp(app.VideoparticledataSwitch.Value, 'True')
                app.Lamp_2.Color = [0, 1, 0];
            else
                app.Lamp_2.Color = [1, 1, 0];
            end
        end

        % Value changed function: PlottedparticledataSwitch
        function PlottedparticledataSwitchValueChanged(app, event)
            if strcmp(app.PlottedparticledataSwitch.Value, 'True') %changes colour based on what side the switch is on
                app.Lamp_3.Color = [0, 1, 0];
            else
                app.Lamp_3.Color = [1, 1, 0];
            end
        end

        % Value changed function: RemovepointsCheckBox
        function RemovepointsCheckBoxValueChanged(app, event)
            value = app.RemovepointsCheckBox.Value;
            
            %uses either the original particle # or a new on to define limits for the point removal spinner
            if app.isMosaic == false
                load ([app.dispDataPath, app.dispDataName],'particle_bk');
            else
                tempArray = readtable([app.dispDataPath, app.dispDataName]);%works with pre-2019 matlab. more importantly, if there are headings, we still get the same data
                tempArray = table2array(tempArray);
                if tempArray(1,1) == (tempArray(2,1)-1) && tempArray(2,1) == (tempArray(3,1)-1)
                    tempArray = tempArray(:,2:5);
                else
                    tempArray = tempArray(:,1:4); %remove columns we don't use
                end
                particleNumber = max(tempArray,[],1); %finds maximum number in the first column (which is the amount of particles)
                particleNumber = particleNumber(1,1);
                frameNumber = max(tempArray,[],1); %Same as above but finds the frame # from the second column
                frameNumber = frameNumber(1,2) + 1;
                particle_bk = nan(frameNumber,2,particleNumber); %fills particle_bk with nans so our code can interpret the holes in the mosaic data
                
                g = 1;
                for i = 1:particleNumber %loops through all the mosaic data and puts it into the 3d array defined above
                    while g < size(tempArray,1) && (tempArray(g+1,2) > tempArray(g,2))
                        particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                        particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                        g = g + 1;
                    end
                    particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                    particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                    if g < size(tempArray,1)
                        g = g + 1;
                    end
                end
            end
            
            
            if isempty(app.propPart) == false
                particle_bk = app.propPart;
            end
            if value == true
                app.PointtoremoveSpinner.Enable = true; %enables everything for point removal
                app.PointtoremoveSpinner.Visible = true;
                app.SelectpointButton.Visible = true;
                app.PointtoremoveSpinnerLabel.Visible = true;
                app.PointtoremoveSpinnerLabel.Enable = true;
                app.SelectpointButton.Enable = true;
                app.PointtoremoveSpinner.Limits = [1,size(particle_bk,3)]; %defines limits based on particle loaded above
                app.RecoverdeletedpointsCheckBox.Value = false;
            else
                app.PointtoremoveSpinner.Enable = false; %disables everything for point removal
                app.PointtoremoveSpinner.Visible = false;
                app.PointtoremoveSpinnerLabel.Visible = false;
                app.PointtoremoveSpinnerLabel.Enable = false;
                app.SelectpointButton.Visible = false;
                app.SelectpointButton.Enable = false;
                app.StepsizeEditField.Limits = [1, size(particle_bk,3)/2];
                app.pointsToRemove = []; %resets the points to remove
            end
        end

        % Button pushed function: SelectpointButton
        function SelectpointButtonPushed(app, event)
            app.pointsToRemove = [app.pointsToRemove,app.PointtoremoveSpinner.Value]; %adds point to be removed
            app.SelectpointButton.Enable = false;
            app.StepsizeEditField.Limits = [1, size(particle,3) - size(app.pointsToRemove,2)];
        end

        % Value changed function: FrameviewSpinner
        function FrameviewSpinnerValueChanged(app, event)
            value = app.FrameviewSpinner.Value;
            app.printImage(value);
        end

        % Value changed function: PointtoremoveSpinner
        function PointtoremoveSpinnerValueChanged(app, event)
            value = app.PointtoremoveSpinner.Value;
            check = false;
            for i = 1:size(app.pointsToRemove,2) %checks to see if the point in the spinner is already set to be removed
                if value == app.pointsToRemove(1,i)
                    check = true;
                end
            end
            if check == true
                app.SelectpointButton.Enable = false;
            else
                app.SelectpointButton.Enable = true;
            end
        end

        % Button pushed function: ShowPointsButton
        function ShowPointsButtonPushed(app, event)
            
            if isempty(app.propPart) == false
                particle_bk = app.propPart; %either shows the points from the original particle or the property particle if it exists
            else
                if ~app.isMosaic
                    load ([app.dispDataPath, app.dispDataName],'particle_bk');
                else
                    tempArray = readtable([app.dispDataPath, app.dispDataName]);%works with pre-2019 matlab. more importantly, if there are headings, we still get the same data
                    tempArray = table2array(tempArray);
                    if tempArray(1,1) == (tempArray(2,1)-1) && tempArray(2,1) == (tempArray(3,1)-1)
                        tempArray = tempArray(:,2:5);
                    else
                        tempArray = tempArray(:,1:4); %remove columns we don't use
                    end
                    particleNumber = max(tempArray,[],1); %finds maximum number in the first column (which is the amount of particles)
                    particleNumber = particleNumber(1,1);
                    frameNumber = max(tempArray,[],1); %Same as above but finds the frame # from the second column
                    frameNumber = frameNumber(1,2) + 1;
                    particle_bk = nan(frameNumber,2,particleNumber); %fills particle_bk with infinities so our code can interpret the holes in the mosaic data
                    
                    g = 1;
                    for i = 1:particleNumber %loops through all the mosaic data and puts it into the 3d array defined above
                        while g < size(tempArray,1) && (tempArray(g+1,2) > tempArray(g,2))
                            particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                            particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                            g = g + 1;
                        end
                        particle_bk(tempArray(g,2)+1,1,i) = tempArray(g,3);
                        particle_bk(tempArray(g,2)+1,2,i) = tempArray(g,4);
                        if g < size(tempArray,1)
                            g = g + 1;
                        end
                    end
                end
                
            end
            
            part2d = zeros(size(particle_bk, 3), 2);
            for i = 1:size(particle_bk,3)
                part2d(i,:) = particle_bk(1,:,i); %creates a 2d version of particle
            end
            minDist = inf;
            for i = 1:size(part2d,1)
                for j = 1:size(part2d,1)
                    if sqrt((part2d(i,1) - part2d(j,1))^2 + (part2d(i,2) - part2d(j,2))^2) < minDist && sqrt((part2d(i,1) - part2d(j,1))^2 + (part2d(i,2) - part2d(j,2))^2)~= 0
                        minDist = sqrt((part2d(i,1) - part2d(j,1))^2 + (part2d(i,2) - part2d(j,2))^2);
                    end
                end
            end
            imgA = imread([app.dispDataPath, app.a(1).name]);
            [r, c, ~] = size(imgA);
            roi = r*c*size(particle_bk, 3)/2000000;
            if roi > minDist
                roi = minDist*3;
            end
            ShowPoints(part2d,imgA,roi,app.pointsToRemove) %show the points with points to be removed in green
        end

        % Value changed function: RecoverdeletedpointsCheckBox
        function RecoverdeletedpointsCheckBoxValueChanged(app, event)
            value = app.RecoverdeletedpointsCheckBox.Value;
            if value == true
                app.RemovepointsCheckBox.Value = false; %if you are to recover points make sure you cannot also remove points
                app.PointtoremoveSpinner.Enable = false;
                app.PointtoremoveSpinner.Visible = false;
                app.PointtoremoveSpinnerLabel.Visible = false;
                app.PointtoremoveSpinnerLabel.Enable = false;
                app.SelectpointButton.Visible = false;
                app.SelectpointButton.Enable = false;
                app.pointsToRemove = [];
            end
        end

        % Value changed function: RemovenoiseCheckBox
        function RemovenoiseCheckBoxValueChanged(app, event)
            value = app.RemovenoiseCheckBox.Value;
            if value == true
                app.DeviationfrommedianEditField.Enable = true;
                app.DeviationfrommedianEditFieldLabel.Enable = true;
                app.Label_3.Enable = true;
            else
                app.DeviationfrommedianEditField.Enable = false;
                app.DeviationfrommedianEditFieldLabel.Enable = false;
                app.Label_3.Enable = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackingAnalysisWizardUIFigure = uifigure;
            app.TrackingAnalysisWizardUIFigure.Name = 'Tracking Analysis Wizard';
            app.LoaddisplacementsButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.LoaddisplacementsButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddisplacementsButtonPushed, true);
            app.LoaddisplacementsButton.Tooltip = {'Make sure the images are in the same path as the displacements.'};
            app.LoaddisplacementsButton.Text = 'Load displacements';
            app.RemovepointsCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.RemovepointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovepointsCheckBoxValueChanged, true);
            app.RemovepointsCheckBox.Enable = 'off';
            app.RemovepointsCheckBox.Tooltip = {'Remove troublesome points as necessary.'};
            app.RemovepointsCheckBox.Text = 'Remove points';
            app.IgnoretransformCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.IgnoretransformCheckBox.Tooltip = {'Ignores transform from stabilization'};
            app.IgnoretransformCheckBox.Text = 'Ignore transform';
            app.MakevideoCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.MakevideoCheckBox.Text = 'Make video';
            app.MakeplotsCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.MakeplotsCheckBox.Text = 'Make plots';
            app.TrackedParticleAnalyzerLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.TrackedParticleAnalyzerLabel.Text = 'Tracked Particle Analyzer';
            app.ExecuteButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Tooltip = {'order 66'};
            app.ExecuteButton.Text = 'Execute';
            app.PlottedparticledataSwitchLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.PlottedparticledataSwitchLabel.Text = 'Plotted particle data';
            app.PlottedparticledataSwitch = uiswitch(app.TrackingAnalysisWizardUIFigure, 'slider');
            app.PlottedparticledataSwitch.Items = {'Smoothed', 'True'};
            app.PlottedparticledataSwitch.ValueChangedFcn = createCallbackFcn(app, @PlottedparticledataSwitchValueChanged, true);
            app.PlottedparticledataSwitch.Tooltip = {'Determines whether the filtered or raw data is used for plotting.'};
            app.PlottedparticledataSwitch.Value = 'True';
            app.VideoparticledataSwitchLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.VideoparticledataSwitchLabel.Text = 'Video particle data';
            app.VideoparticledataSwitch = uiswitch(app.TrackingAnalysisWizardUIFigure, 'slider');
            app.VideoparticledataSwitch.Items = {'Smoothed', 'True'};
            app.VideoparticledataSwitch.ValueChangedFcn = createCallbackFcn(app, @VideoparticledataSwitchValueChanged, true);
            app.VideoparticledataSwitch.Tooltip = {'Determines whether the filtered or raw data is used for the video.'};
            app.VideoparticledataSwitch.Value = 'Smoothed';
            app.Label = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.Label.Text = '';
            app.Lamp_3 = uilamp(app.TrackingAnalysisWizardUIFigure);
            app.Label_2 = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.Label_2.Text = '';
            app.Lamp_2 = uilamp(app.TrackingAnalysisWizardUIFigure);
            app.PointtoremoveSpinnerLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.PointtoremoveSpinnerLabel.Enable = 'off';
            app.PointtoremoveSpinnerLabel.Visible = 'off';
            app.PointtoremoveSpinnerLabel.Text = 'Point to remove';
            app.PointtoremoveSpinner = uispinner(app.TrackingAnalysisWizardUIFigure);
            app.PointtoremoveSpinner.Limits = [1 Inf];
            app.PointtoremoveSpinner.ValueChangedFcn = createCallbackFcn(app, @PointtoremoveSpinnerValueChanged, true);
            app.PointtoremoveSpinner.Enable = 'off';
            app.PointtoremoveSpinner.Visible = 'off';
            app.PointtoremoveSpinner.Value = 1;
            app.SelectpointButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.SelectpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointButtonPushed, true);
            app.SelectpointButton.Enable = 'off';
            app.SelectpointButton.Visible = 'off';
            app.SelectpointButton.Text = 'Select point';
            app.UIAxes = uiaxes(app.TrackingAnalysisWizardUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.ColourScaleSwitchLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.ColourScaleSwitchLabel.Tooltip = {'Determines whether the net displacement or displacement since previous frame (frame-by-frame displacement) is used to scale the colour of drawn arrows.'};
            app.ColourScaleSwitchLabel.Text = 'Colour Scale';
            app.ColourScaleSwitch = uiswitch(app.TrackingAnalysisWizardUIFigure, 'slider');
            app.ColourScaleSwitch.Items = {'Total', 'FBF'};
            app.ColourScaleSwitch.Tooltip = {'Determines whether the net displacement or displacement since previous frame (frame-by-frame displacement) is used to scale the colour of drawn arrows.'};
            app.ColourScaleSwitch.Value = 'Total';
            app.ArrowScaleSwitchLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.ArrowScaleSwitchLabel.Tooltip = {'Determines whether the net displacement or displacement since previous frame (frame-by-frame displacement) is used to scale the drawn arrow lengths.'};
            app.ArrowScaleSwitchLabel.Text = 'Arrow Scale';
            app.ArrowScaleSwitch = uiswitch(app.TrackingAnalysisWizardUIFigure, 'slider');
            app.ArrowScaleSwitch.Items = {'Total', 'FBF'};
            app.ArrowScaleSwitch.Tooltip = {'Determines whether the net displacement or displacement since previous frame (frame-by-frame displacement) is used to scale the drawn arrow lengths.'};
            app.ArrowScaleSwitch.Value = 'Total';
            app.DilationfactorEditFieldLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.DilationfactorEditFieldLabel.Tooltip = {'Dilates drawn arrows by entered ratio when per-frame displacement is used (for if displacement is imperceptibly small). Typical values range from 3-10% depending on the original displacement size.'};
            app.DilationfactorEditFieldLabel.Text = 'Dilation factor';
            app.DilationfactorEditField = uieditfield(app.TrackingAnalysisWizardUIFigure, 'numeric');
            app.DilationfactorEditField.Limits = [0 Inf];
            app.DilationfactorEditField.Tooltip = {'Dilates drawn arrows by entered ratio (typically for frame-by-frame'; ' if displacement is imperceptibly small). Typical values range from 10-15x depending on the original displacement magnitude.'};
            app.DilationfactorEditField.Value = 1;
            app.XLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.XLabel.Text = 'X';
            app.EnsureimagestackisinthesamefolderbeforeloadingdataLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.EnsureimagestackisinthesamefolderbeforeloadingdataLabel.Text = 'Ensure image stack is in the same folder as the displacements before loading.';
            app.FrameviewSpinnerLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.FrameviewSpinnerLabel.Enable = 'off';
            app.FrameviewSpinnerLabel.Text = 'Frame view';
            app.FrameviewSpinner = uispinner(app.TrackingAnalysisWizardUIFigure);
            app.FrameviewSpinner.Limits = [0 Inf];
            app.FrameviewSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameviewSpinnerValueChanged, true);
            app.FrameviewSpinner.Enable = 'off';
            app.ShowPointsButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.ShowPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPointsButtonPushed, true);
            app.ShowPointsButton.Enable = 'off';
            app.ShowPointsButton.Visible = 'off';
            app.ShowPointsButton.Text = 'Show Points';
            app.RecoverdeletedpointsCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.RecoverdeletedpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoverdeletedpointsCheckBoxValueChanged, true);
            app.RecoverdeletedpointsCheckBox.Text = 'Recover deleted points';
            app.ArrowplacementvideoButtonGroup = uibuttongroup(app.TrackingAnalysisWizardUIFigure);
            app.ArrowplacementvideoButtonGroup.Title = 'Arrow placement (video)';
            app.HeadButton = uiradiobutton(app.ArrowplacementvideoButtonGroup);
            app.HeadButton.Text = 'Head';
            app.HeadButton.Value = true;
            app.MidpointButton = uiradiobutton(app.ArrowplacementvideoButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.TailButton = uiradiobutton(app.ArrowplacementvideoButtonGroup);
            app.TailButton.Text = 'Tail';
            app.WorkinbackgroundCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.WorkinbackgroundCheckBox.Text = 'Work in background';
            app.FramethresholdEditFieldLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.FramethresholdEditFieldLabel.Enable = 'off';
            app.FramethresholdEditFieldLabel.Text = 'Frame threshold';
            app.FramethresholdEditField = uieditfield(app.TrackingAnalysisWizardUIFigure, 'numeric');
            app.FramethresholdEditField.Limits = [1 Inf];
            app.FramethresholdEditField.Enable = 'off';
            app.FramethresholdEditField.Value = 1;
            app.RemovenoiseCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.RemovenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovenoiseCheckBoxValueChanged, true);
            app.RemovenoiseCheckBox.Text = 'Remove noise';
            app.DeviationfrommedianEditFieldLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.DeviationfrommedianEditFieldLabel.Enable = 'off';
            app.DeviationfrommedianEditFieldLabel.Text = 'Deviation from median';
            app.DeviationfrommedianEditField = uieditfield(app.TrackingAnalysisWizardUIFigure, 'numeric');
            app.DeviationfrommedianEditField.Enable = 'off';
            app.Label_3 = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '%';
            app.StepsizeEditFieldLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.TrackingAnalysisWizardUIFigure, 'numeric');
            app.StepsizeEditField.Limits = [1 Inf];
            app.StepsizeEditField.RoundFractionalValues = 'on';
            app.StepsizeEditField.Tooltip = {'Skip frames - this can make data smoother by reducing fidgeting/shakiness'};
            app.StepsizeEditField.Value = 1;
            app.FixframeCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.FixframeCheckBox.Tooltip = {'Fixes the background to the first frame'};
            app.FixframeCheckBox.Text = 'Fix frame';
            app.FrameRateEditFieldLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.TrackingAnalysisWizardUIFigure, 'numeric');
            app.FrameRateEditField.Limits = [1 Inf];
            app.FrameRateEditField.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = analysisApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackingAnalysisWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackingAnalysisWizardUIFigure)
        end
    end
end