classdef newTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticleTrackingWizardUIFigure  matlab.ui.Figure
        LoadstackButton                 matlab.ui.control.Button
        StarttrackingButton             matlab.ui.control.Button
        ROIsizeEditFieldLabel           matlab.ui.control.Label
        ROIsizeEditField                matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        SavedisplacementsCheckBox       matlab.ui.control.CheckBox
        ParticleTrackerLabel            matlab.ui.control.Label
        OpenAnalysisButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        FramepreviewSpinnerLabel        matlab.ui.control.Label
        FramepreviewSpinner             matlab.ui.control.Spinner
        StartFrameLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AdjuststartButton               matlab.ui.control.Button
        RemovedifficultpointsduringtrackingDropDownLabel  matlab.ui.control.Label
        RemovedifficultpointsduringtrackingDropDown  matlab.ui.control.DropDown
        MaxtravelEditFieldLabel         matlab.ui.control.Label
        MaxtravelEditField              matlab.ui.control.NumericEditField
        MaxROIincreaseEditFieldLabel    matlab.ui.control.Label
        MaxROIincreaseEditField         matlab.ui.control.NumericEditField
        EnablesoundsDropDownLabel       matlab.ui.control.Label
        EnablesoundsDropDown            matlab.ui.control.DropDown
        NotifymeiftoomanypointsarebeingdeletedCheckBox  matlab.ui.control.CheckBox
        BitshiftEditFieldLabel          matlab.ui.control.Label
        BitshiftEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        a; %object to store properties of stack
        nfile; %number of files from stack to be used
        startFrame; %first frame
        filename; %name of first frame
        path; %path of stack
        dispLoaded = false; % boolean to determine whether the displacements have been loaded
        dispDataName; %name and path of displacement data
        dispDataPath;
    end
    
    methods (Access = private)
        
        function printImage(app, frame)
            imgA = imread([app.path, app.a(frame).name]);
            app.UIAxes.Visible = true; %make axes appear
            I = imshow(imgA,'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            app.UIAxes.XLim = [0 I.XData(2)]; % resizes image to fit frame
            app.UIAxes.YLim = [0 I.YData(2)];            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; 
        end

        % Button pushed function: LoadstackButton
        function LoadstackButtonPushed(app, event)
            oldFile = app.filename; oldPath = app.path;
            [app.filename, app.path]=uigetfile('*.tif'); %get stack and store properties in a
            if app.filename == 0  %if the user closes the window keep the previous stack
               app.filename = oldFile;
               app.path = oldPath;
               uiconfirm(app.ParticleTrackingWizardUIFigure,'The user cancelled the file select.', 'No stack chosen', 'Options', {'OK'}, 'Icon', 'error');
               app.ParticleTrackingWizardUIFigure.Visible = false;
               app.ParticleTrackingWizardUIFigure.Visible = true;
               return;
            end
            app.a = dir([app.path '*.tif']);
            [~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
            app.a = app.a(reindex);
            app.startFrame = find(strcmp({app.a.name}, app.filename)==1); %first frame is the one chosen
            app.nfile = max(size(app.a));    %% take the number of files and store it in nfile
           
            
            app.StarttrackingButton.Enable = true;
            app.ParticleTrackingWizardUIFigure.Visible = false;
            app.ParticleTrackingWizardUIFigure.Visible = true;
            app.FramepreviewSpinner.Limits = [1, max(size(app.a))];
            app.FramepreviewSpinner.Enable = true;
            app.FramepreviewSpinnerLabel.Enable = true;
            app.FramepreviewSpinner.Value = app.startFrame;
            app.printImage(app.startFrame);
            app.StartFrameLabel.Enable = true;
            app.Label.Text = int2str(app.startFrame);
            app.AdjuststartButton.Enable = true;
        end

        % Button pushed function: StarttrackingButton
        function StarttrackingButtonPushed(app, event)
            check = uiconfirm(app.ParticleTrackingWizardUIFigure,'Run tracker with the selected options?', 'Confirmation', 'Options', {'Run', 'Cancel'}, 'DefaultOption', 2);
            if strcmp(check, 'Run')
                imgA = imread([app.path, app.filename]);%get image
                ROISize = app.ROIsizeEditField.Value;%user parameters
                numPoints = app.NumberofpointsEditField.Value;
                trackPts = SelectNPoints(imgA, ROISize, numPoints, 0);%track the points
                if isnan(trackPts) == true%if the prior fcn returned a nan, it means the user cancelled
                    return;
                end
                d = uiprogressdlg(app.ParticleTrackingWizardUIFigure, 'Title', 'Tracking your points. Please Wait...', 'Cancelable', 'on'); %initialize loading bar
                %progress bar
                %       %%%old particle variable       steps = zeros(numPoints, 2*(app.nfile - 1)); %%using struct particle now % pre-sizing array to reduce computation time
              
                %        %%%another old index      q = 1;
                particle = zeros(app.nfile-app.startFrame,2,numPoints); %preallocate storage of particles
                totalRemovedPoints = 0;
                if strcmp(app.RemovedifficultpointsduringtrackingDropDown.Value, 'All') %either remove all points or prompt use (inside tracknext)
                    remPts = 1;
                else
                    remPts = 0;
                end
                
                if strcmp(app.EnablesoundsDropDown, 'All') || strcmp(app.EnablesoundsDropDown, 'When stuck') %activate sounds (inside tracknext)
                    userPromptSound = true;
                else
                    userPromptSound = false;
                end
                k = 1;%counter for frame #
                dontAskAgain = false;
                originalParticleNum = size(particle, 3);
                for i = app.startFrame + 1 : app.nfile %index from startframe to end
                    imgB = imread(sprintf('%s%s', app.path, app.a(i).name));%get frame i
                    imgB = imflatfield(imgB, 10);%flatfield corr'n of frame i w/ SD = 10
                    imgB = imsharpen(imgB); %sharpen frame i
                    if app.BitshiftEditField.Value ~= 0
                        bitshift(imgB,app.BitshiftEditField.Value); %bitshift the image by the specified value
                    end
                    if app.NotifymeiftoomanypointsarebeingdeletedCheckBox.Value == true %check if more than half of the points have been deleted, if so prompt user to abort tracking
                        if size(particle,3) < originalParticleNum/2 && dontAskAgain == false
                            deletionConfirm = uiconfirm(app.ParticleTrackingWizardUIFigure, ['Less than half of the original ', int2str(originalParticleNum), ' points remain.  Do you wish to continue?'], 'Lots of point deletions!', 'Icon', 'warning', 'Options', {'Abort tracking', 'Continue', 'Continue & don''t ask me again'}, 'DefaultOption', 'Continue');
                            if strcmp(deletionConfirm, 'Abort tracking')
                                return;
                            elseif strcmp(deletionConfirm, 'Continue & don''t ask me again')
                                dontAskAgain = true;
                            end
                        end
                    end
                    if exist('keepAskingCheck', 'var') && keepAskingCheck == false
                        remPts = 1;
                    end
                    %none of this works yet but process will be explained (though logic is not sound)
%                     if k > 30 %need 30 pts for better curve
%  
%                        
% 
%                         for u = 1:size(particle,3)                            
%                             %[quadFit, ~, ~] = polyfit(particle(k-30:k-1,1,u), particle(k-30:k-1,2,u), 2);%both methods yield negatives and weird parabolas
%                             quadFit = polyfit(particle(k-30:k-1,1,u), particle(k-30:k-1,2,u), 2);%apparently adding 2 outputs is more accurate but anecdotally has only lead to strange values
%                             avgHorDist = (particle(k-1,1,u)-particle(k-30,1,u))/29; %calculate avg distance traveled per frame in last 30, assume particle will travel that much now
%                             trackPts(u,1) = particle(k-1,1,u) + avgHorDist;
%                             trackPts(u,2) = quadFit(1,1)*trackPts(u,1)^2 + quadFit(1,2)*trackPts(u,1) + quadFit(1,3);%predict y with that x. now start search at this pt
%                         end
%                     end
%                     
                    [newPts, removedPointsInFrame, keepAskingCheck] = TrackNext(imgB, ROISize, trackPts,remPts, app.MaxtravelEditField.Value, app.MaxROIincreaseEditField.Value, userPromptSound); %track the points for next frame
                    if isnan(newPts) == true
                       return;
                    end
                    totalRemovedPoints = totalRemovedPoints + removedPointsInFrame;
                    h = 1;
                    while h <= numPoints
                        if isinf(newPts (h,1)) == true %if the point was ignored in tracknext, delete it
                            newPts (h,:) = [];
                            numPoints = numPoints - 1;
                            particle(:,:,h) = [];
                            h = h-1;
                        end
                        h = h+1;
                    end
                    trackPts = newPts;
                    for j = 1:numPoints%add the new, non-ignored pts to the particle array
                        particle(k,1,j) = newPts(j,1);
                        particle(k,2,j) = newPts(j,2);
                    end
                    
                    %                 for j = 1:numPoints
                    %                     particle(:,1,j) = (steps(j,1:2:end))';
                    %                     particle(:,2,j) = (steps(j,2:2:end))';
                    %                 end
                    %                 for j = 1:numPoints
                    %                    particles(j).x(i-1) = newPts(j,1);
                    %                   particles(j).y(i-1) = newPts(j,2);
                    %                 end
                    %                  steps(1:end,q:q+1) = newPts;%we should add that other dimension at some point.. for now just going every two and optimizing that method a bit
                    %                   q = q+2;
                    k = k+1;
                    fraction = (i-app.startFrame)/(app.nfile-app.startFrame);%proportion of tracking done
                    d.Value = fraction;%value of proportion for bar
                    percent = fraction*100;%percentage
                    if rem(round(percent,1, 'decimals'), 1) == 0
                        d.Message = [num2str(round(percent, 1, 'decimals')), '.0% complete.'];
                    else
                        d.Message = [num2str(round(percent, 1, 'decimals')), '% complete.'];
                    end
                        %display the percent completion rather than decimal
                    if d.CancelRequested%when closed, stop the process
                        close(d)
                        return;
                    end
                end
                
                if strcmp(app.EnablesoundsDropDown.Value, 'All') || strcmp(app.EnablesoundsDropDown.Value, 'On completion')
                    beep on;
                    beep
                end
                
                if remPts == 1
                    uiconfirm(app.ParticleTrackingWizardUIFigure,['Process completed with ', int2str(totalRemovedPoints), ' point deletions'], 'That''s all folks', 'Options', {'OK'}, 'Icon', 'success');
                else
                    uiconfirm(app.ParticleTrackingWizardUIFigure,'Tracking COMPLETE!', 'That''s all folks', 'Options', {'OK'}, 'Icon', 'success');
                end
                close(d);


                %displacements
                particle_bk = particle;%when the particle is smoothed, still have stored raw data
                
                %old storage method
                
                %                 steps_bk = steps;
                
                %                                 StepsPerPt = []; % need to figure out what size to pre-allocate for these ones
                %               %  StepsPerPt = zeros(2*app.nfile,2*numPoints);
                %                 filt_steps = [];%need to optimize both the following loops
                %                 filt_particle = zeros(app.nfile-1,2,numPoints);
                %                 for i = 1:size(steps_bk,1)
                %                     StepsPerPt = [StepsPerPt, steps_bk(i, 1:2:end)' steps_bk(i,2:2:end)'];
                %                 end
                %                 for i = 1:2:size(StepsPerPt,2)
                %                     filt_steps = [filt_steps, smoothdata(StepsPerPt(:,i), 'gaussian', 10), smoothdata(StepsPerPt(:, i+1), 'gaussian', 10)];
                %                 end
                
                %smoothed particle array
                filt_particle = zeros(size(particle,1),size(particle,2),size(particle,3));
                for i = 1:numPoints
                    filt_particle(:,1,i) = smoothdata(particle(:,1,i), 'gaussian', 10);
                    filt_particle(:,2,i) = smoothdata(particle(:,2,i), 'gaussian', 10);
                end
                
                %             removePts = [1;2;3;4];
                %             if app.RemovepointsCheckBox.Value == true
                %                StepsPerPtB = StepsPerPt;
                %                filt_stepsB = filt_steps;
                %                for i = 1:length(removePts)
                %                    rmPt = removePts(i) - (i-1); %compensate for removed pts
                %                    StepsPerPtR = RemColumn(StepsPerPt, rmPt*2); %remove y
                %                    StepsPerPtR = RemColumn(StepsPerPt, rmPt*2 - 1); %x
                %                    StepsPerPt = StepsPerPtR;
                % %                    clear StepsPerPtR; %%not sure why this is cleared every single loop,
                %                                     %%and subsequently attempted to be used to index the next loop
                %                end
                %                filt_steps = [];
                %                for i = 1:2:(size(particle, 3)*2)
                %                    filt_steps = [filt_steps, smoothdata(StepsPerPt(:, i), 'gaussian', 10), smoothdata(StepsPerPt(:, i+1), 'gaussian', 10)];
                %                end
                %             end
                
                
                %if displacements are to be saved, save 'em
                if app.SavedisplacementsCheckBox.Value == true
                    pathvar = app.path;
                    filenamevar = app.filename;
                    save(sprintf('%sDisplacementData.mat',app.path),'filt_particle','particle_bk','pathvar','filenamevar');
                end
                
            end


            %%%%%
% for i = 1:1:size(filt_steps,1);
%             %file_name=sprintf('%s%s',path,a(t).name);
%             %t=t+1;
%             %imshow(file_name);
%             %hold on;
%             for u = 1:2:size(filt_steps,2)
%            % plot(filt_steps(i,u),filt_steps(i,u+1),'.','Color',clr(mod(u,5)+1))
%            % colmod = sqrt(filt_steps(i,u)-trans_orig_steps(1,u))^2+ (filt_steps(i,u+1)-trans_orig_steps(1,u+1))^2)
%            
%             GoodArrow(trans_orig_steps(i,u),trans_orig_steps(i,u+1),filt_steps(i,u), filt_steps(i,u+1),ColScale(0,70,Rs(i,(u+1/2))));
%            %Rs(i,(u+1)/2) =  sqrt((filt_steps(i,u) - trans_orig_steps(i,u))^2+(filt_steps(i,u+1) - trans_orig_steps(i,u+1))^2);
%           %  quiver(trans_orig_steps(i,u), trans_orig_steps(i,u+1), filt_steps(i,u)-trans_orig_steps(1,u), filt_steps(i,u+1)-trans_orig_steps(1,u+1),2,'Color',[0 1 0]);  %draw arrow from original point to new point. Original point shifted with frame-wise transform.
%             %hold on;
%           axis equal;
%             end
%            % frame=getframe(trackplot.CurrentAxes);
%             %writeVideo(v,frame);
%             %trackplot.delete;    
%                
% end
        end

        % Callback function
        function LoadpreviousdisplacementsButtonPushed(app, event)
%ORIGINAL ANALYSIS CODE, CAN BE DELETED
        
        
%         [app.dispDataName,app.dispDataPath] =uigetfile('*.mat');%get the matlab file
%         app.LoadedLabel.Visible = 'on';
%         app.dispLoaded = true;
        end

        % Callback function
        function RunAnalysisButtonPushed(app, event)
%ORIGINAL ANALYSIS CODE, CAN BE DELETED
        
%            default = 'No';
%            answer = questdlg('Run analysis with the selected options?','Confirmation','Yes','No',default);
%         
%         
%         
%         if strcmp(answer,'Yes') == 1
%             load ([app.dispDataPath, app.dispDataName], 'filt_particle', 'particle_bk', 'pathvar', 'filenamevar');
%             app.path = app.dispDataPath;
%             app.filename = filenamevar;
%             app.a = dir([app.path '*.tif']);
%             app.nfile = max(size(app.a));    %% take the number of files and store it in nfile
%             [~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
%             app.a = app.a(reindex);
%             app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
%             particle = particle_bk;
%         
%         
%         
%         
%             [tformFile, ~] = sprintf('%stransform.mat',convertCharsToStrings(app.path(1:end-2)));
%             
%             if exist(tformFile, 'file') && app.IgnoretransformCheckBox.Value == false
%                  load(tformFile, '-mat', HsRts);
%             else
%                 HsRts = zeros(3,3,size(particle,1)); 
%                 for i =1:(size(particle, 1))
%                     HsRts(:,:,i) = [1,0,0;0,1,0;0,0,1];
%                 end
%             end
%             
%             original location
%             originalPts2_x = zeros(1,size(particle,3));
%             originalPts2_y = zeros(1,size(particle,3));
%             for i = 1:size(particle,3)
%                 originalPts2_x(1,i) = filt_particle (1,1,i);
%                 originalPts2_y(1,i) = filt_particle (1,2,i);
%             end
%            trans_orig_steps = [];
%              for i = 1:length(HsRts) 
%                 tform = affine2d(HsRts(:,:,i));
%                 [U,V] = transformPointsInverse(tform, originalPts2_x, originalPts2_y);
%                 stepTransPt = [];
%                 for k = 1:length(U)
%                     stepTransPt = [stepTransPt, U(k), V(k)];
%                 end
%                 trans_orig_steps = [trans_orig_steps; stepTransPt];
%             end
%             
%             if app.MakevideoCheckBox.Value == true
%                 clr = 'rgbywp';
%                 t = app.startFrame;
%                 v = VideoWriter(sprintf('%sStepQuiver.avi',app.path),'Motion JPEG AVI'); %NAME OF VIDEO FILE
%                 open(v);
%                 
%                 Rs = zeros(app.nfile - 1,size(particle,3));
%                 for i = 1:size(particle, 1)-1
%                     h = 1;
%                     for u = 1:size(filt_particle, 3)
%                         Rs(i,u) =  sqrt((filt_particle(i+1,1,u) - trans_orig_steps(i+1,h))^2+(filt_particle(i+1,2,u) - trans_orig_steps(i+1,h+1))^2);
%                         h = h+2;
%                     end
%                 end
%                 
%                 for i = 1:size(particle, 1)-1
%                     trackPlot = figure(5);
%                     fileName = sprintf('%s%s', app.path, app.a(t).name);
%                     t = t+1;
%                     imshow(fileName);
%                     hold on;
%                     h = 1;
%                     for u = 1:size(filt_particle,3)
%                         GoodArrow(trans_orig_steps(i,h),trans_orig_steps(i,h+1),filt_particle(i+1,1,u), filt_particle(i+1,2,u),ColScale(min(min(Rs)),max(max(Rs)),Rs(i,u)));
%                         hold on;
%                         h = h+2;
%                     end
%                                        for u = 1:2:size(filt_steps, 2)
%                                            Rs(i,(u+1)/2) =  sqrt((filt_steps(i,u) - trans_orig_steps(i,u))^2+(filt_steps(i,u+1) - trans_orig_steps(i,u+1))^2);
%                                            GoodArrow(trans_orig_steps(i,u),trans_orig_steps(i,u+1),filt_steps(i,u), filt_steps(i,u+1),ColScale(0,66,Rs(i,((u+1)/2))));
%                                            hold on;
%                                        end
%                     frame = getframe(trackPlot.CurrentAxes);
%                     writeVideo(v,frame);
%                     trackPlot.delete;
%                 end
%                 close(v);
%             end
%             
%             if app.MakeplotsCheckBox.Value == true
%                 filt_particle2d = zeros(app.nfile-1,size(filt_particle,3)*2);
%                 k = 1;
%                 for i = 1:2:(size(particle,3))*2
%                     filt_particle2d(:,i) = filt_particle (:,1,k);
%                     filt_particle2d(:,i+1) = filt_particle (:,2,k);
%                     k = k+1;
%                 end
%                 figure(1);plot(filt_particle2d(:,1:2:end)); 
%                 title('X Displacement per Frame');
%                 ylabel('Displacement (px)');
%                 xlabel('Frame');
%                  figure(2);plot(filt_particle2d(:,2:2:end));
%                 title('Y Displacement per Frame');
%                 ylabel('Displacement (px)');
%                 xlabel('Frame');
%                 figure(3); plot(filt_particle2d(:,1:2:end),filt_particle2d(:,2:2:end));
%                 title('X vs Y Displacement: Time History');
%                 ylabel('Y Displacement (px)');
%                 xlabel('X Displacement (px)');
%                 figure(3);axis equal;
%                 figure(4); plot(mean((filt_particle2d(:,2:2:end)-filt_particle2d(1,2:2:end))'))
%                 title('Mean Y Displacement per Frame');
%                 ylabel('Mean Displacement (px)');
%                 xlabel('Frame');
%                 figure(4);axis equal;
%                 figure(5); plot(mean((filt_particle2d(:,1:2:end)-filt_particle2d(1,1:2:end))'))
%                 title('Mean X Displacement per Frame');
%                 ylabel('Mean Displacement (px)');
%                 xlabel('Frame');
%                 figure(5);axis equal;
%                 
%                 saveas(figure(1),sprintf('%sXDisplacement.png',app.path));
%                 saveas(figure(2),sprintf('%sYDisplacement.png',app.path));
%                 saveas(figure(3),sprintf('%sXYTime.png',app.path));
%                 saveas(figure(4),sprintf('%sAverageY.png',app.path));
%                 saveas(figure(5),sprintf('%sAverageX.png',app.path));
%                 button = questdlg('The analysis is complete.', 'Attention', 'OK', 'Close all','Close all');
%                 if strcmp(button, 'Close all') 
%                     close all;
%                 end
%             end
%         end
            
            
        end

        % Button pushed function: OpenAnalysisButton
        function OpenAnalysisButtonPushed(app, event)
            analysisApp;
            close(app.ParticleTrackingWizardUIFigure);
        end

        % Value changed function: FramepreviewSpinner
        function FramepreviewSpinnerValueChanged(app, event)
            value = app.FramepreviewSpinner.Value;
            app.printImage(value);
        end

        % Button pushed function: AdjuststartButton
        function AdjuststartButtonPushed(app, event)
            app.startFrame = app.FramepreviewSpinner.Value;
            app.Label.Text = int2str(app.startFrame);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParticleTrackingWizardUIFigure = uifigure('Visible', 'off');
            app.ParticleTrackingWizardUIFigure.Name = 'Particle Tracking Wizard';
            app.LoadstackButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.LoadstackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstackButtonPushed, true);
            app.LoadstackButton.Tooltip = {'Choose the first frame in the stack to be used.'};
            app.LoadstackButton.Text = 'Load stack';
            app.StarttrackingButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.StarttrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StarttrackingButtonPushed, true);
            app.StarttrackingButton.Enable = 'off';
            app.StarttrackingButton.Text = 'Start tracking';
            app.ROIsizeEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.ROIsizeEditField.Limits = [1 Inf];
            app.ROIsizeEditField.Value = 5;
            app.NumberofpointsEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.NumberofpointsEditField.Limits = [1 Inf];
            app.NumberofpointsEditField.Value = 1;
            app.SavedisplacementsCheckBox = uicheckbox(app.ParticleTrackingWizardUIFigure);
            app.SavedisplacementsCheckBox.Text = 'Save displacements';
            app.SavedisplacementsCheckBox.Value = true;
            app.ParticleTrackerLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.ParticleTrackerLabel.Text = 'Particle Tracker';
            app.OpenAnalysisButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.OpenAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAnalysisButtonPushed, true);
            app.OpenAnalysisButton.Text = 'Open Analysis';
            app.UIAxes = uiaxes(app.ParticleTrackingWizardUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.FramepreviewSpinnerLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.FramepreviewSpinnerLabel.Enable = 'off';
            app.FramepreviewSpinnerLabel.Text = 'Frame preview';
            app.FramepreviewSpinner = uispinner(app.ParticleTrackingWizardUIFigure);
            app.FramepreviewSpinner.ValueChangedFcn = createCallbackFcn(app, @FramepreviewSpinnerValueChanged, true);
            app.FramepreviewSpinner.Enable = 'off';
            app.StartFrameLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.StartFrameLabel.Enable = 'off';
            app.StartFrameLabel.Text = 'Start Frame:';
            app.Label = uilabel(app.ParticleTrackingWizardUIFigure);
            app.Label.Text = '';
            app.AdjuststartButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.AdjuststartButton.ButtonPushedFcn = createCallbackFcn(app, @AdjuststartButtonPushed, true);
            app.AdjuststartButton.Enable = 'off';
            app.AdjuststartButton.Tooltip = {'Adjusts the start frame to be that currently selected.'};
            app.AdjuststartButton.Text = 'Adjust start';
            app.RemovedifficultpointsduringtrackingDropDownLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.RemovedifficultpointsduringtrackingDropDownLabel.Text = 'Remove difficult points during tracking';
            app.RemovedifficultpointsduringtrackingDropDown = uidropdown(app.ParticleTrackingWizardUIFigure);
            app.RemovedifficultpointsduringtrackingDropDown.Items = {'When specified', 'All'};
            app.RemovedifficultpointsduringtrackingDropDown.Tooltip = {'If the tracker uses every method and still can''t find the point this determines whether the point will be deleted or the user will be prompted to manually select it.'};
            app.RemovedifficultpointsduringtrackingDropDown.Value = 'When specified';
            app.MaxtravelEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.MaxtravelEditFieldLabel.Text = 'Max travel';
            app.MaxtravelEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.MaxtravelEditField.Limits = [1 Inf];
            app.MaxtravelEditField.Value = 10;
            app.MaxROIincreaseEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.MaxROIincreaseEditFieldLabel.Text = 'Max ROI increase';
            app.MaxROIincreaseEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.MaxROIincreaseEditField.Limits = [0 Inf];
            app.MaxROIincreaseEditField.Tooltip = {'The maximum size by which the ROI will increase totally when having trouble finding the next point.'};
            app.MaxROIincreaseEditField.Value = 15;
            app.EnablesoundsDropDownLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.EnablesoundsDropDownLabel.Text = 'Enable sounds';
            app.EnablesoundsDropDown = uidropdown(app.ParticleTrackingWizardUIFigure);
            app.EnablesoundsDropDown.Items = {'Off', 'On completion', 'When stuck', 'All'};
            app.EnablesoundsDropDown.Value = 'Off';
            app.NotifymeiftoomanypointsarebeingdeletedCheckBox = uicheckbox(app.ParticleTrackingWizardUIFigure);
            app.NotifymeiftoomanypointsarebeingdeletedCheckBox.Tooltip = {'pushes a notification when less than half the original points remain'};
            app.NotifymeiftoomanypointsarebeingdeletedCheckBox.Text = 'Notify me if too many points are being deleted';
            app.BitshiftEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.BitshiftEditFieldLabel.Text = 'Bitshift';
            app.BitshiftEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.BitshiftEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.ParticleTrackingWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newTrackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticleTrackingWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticleTrackingWizardUIFigure)
        end
    end
end