classdef analysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackingAnalysisWizardUIFigure  matlab.ui.Figure
        LoadpreviousdisplacmentsButton  matlab.ui.control.Button
        LoadedLabel                     matlab.ui.control.Label
        RemovepointsCheckBox            matlab.ui.control.CheckBox
        IgnoretransformCheckBox         matlab.ui.control.CheckBox
        MakevideoCheckBox               matlab.ui.control.CheckBox
        MakeplotsCheckBox               matlab.ui.control.CheckBox
        AnalysisLabel                   matlab.ui.control.Label
        RunAnalysisButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        a;
        nfile;
        startFrame;
        filename;
        path;
        dispLoaded = false;
        dispDataName;
        dispDataPath;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadpreviousdisplacmentsButton
        function LoadpreviousdisplacmentsButtonPushed(app, event)
        [app.dispDataName,app.dispDataPath] =uigetfile('*.mat');
        app.LoadedLabel.Visible = 'on';
        app.dispLoaded = true;
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
         default = 'No';
           answer = questdlg('Run analysis with the selected options?','Confirmation','Yes','No',default);


        if strcmp(answer,'Yes') == 1
            load ([app.dispDataPath, app.dispDataName], 'filt_particle', 'particle_bk', 'pathvar', 'filenamevar');
            app.path = app.dispDataPath;
            app.filename = filenamevar;
            app.a = dir([app.path '*.tif']);
            app.nfile = max(size(app.a));    %% take the number of files and store it in nfile
            [~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
            app.a = app.a(reindex);
            app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
            particle = particle_bk;


            [tformFile, ~] = sprintf('%stransform.mat',convertCharsToStrings(app.path(1:end-2)));
            
            if exist(tformFile, 'file') && app.IgnoretransformCheckBox.Value == false
                 load(tformFile, '-mat', HsRts);
            else
                HsRts = zeros(3,3,size(particle,1)); 
                for i =1:(size(particle, 1))
                    HsRts(:,:,i) = [1,0,0;0,1,0;0,0,1];
                end
            end
            
            %original location
            originalPts2_x = zeros(1,size(particle,3));
            originalPts2_y = zeros(1,size(particle,3));
            for i = 1:size(particle,3)
                originalPts2_x(1,i) = filt_particle (1,1,i);
                originalPts2_y(1,i) = filt_particle (1,2,i);
            end
           trans_orig_steps = [];
             for i = 1:length(HsRts) 
                tform = affine2d(HsRts(:,:,i));
                [U,V] = transformPointsInverse(tform, originalPts2_x, originalPts2_y);
                stepTransPt = [];
                for k = 1:length(U)
                    stepTransPt = [stepTransPt, U(k), V(k)];
                end
                trans_orig_steps = [trans_orig_steps; stepTransPt];
            end
            
            if app.MakevideoCheckBox.Value == true
                clr = 'rgbywp';
                t = app.startFrame;
                v = VideoWriter(sprintf('%sStepQuiver.avi',app.path),'Motion JPEG AVI'); %NAME OF VIDEO FILE
                open(v);
                
                Rs = zeros(app.nfile - 1,size(particle,3));
                for i = 1:size(particle, 1)-1
                    h = 1;
                    for u = 1:size(filt_particle, 3)
                        Rs(i,u) =  sqrt((filt_particle(i+1,1,u) - trans_orig_steps(i+1,h))^2+(filt_particle(i+1,2,u) - trans_orig_steps(i+1,h+1))^2);
                        h = h+2;
                    end
                end
                
                for i = 1:size(particle, 1)-1
                    trackPlot = figure(5);
                    fileName = sprintf('%s%s', app.path, app.a(t).name);
                    t = t+1;
                    imshow(fileName);
                    hold on;
                    h = 1;
                    for u = 1:size(filt_particle,3)
                        GoodArrow(trans_orig_steps(i,h),trans_orig_steps(i,h+1),filt_particle(i+1,1,u), filt_particle(i+1,2,u),ColScale(min(min(Rs)),max(max(Rs)),Rs(i,u)));
                        hold on;
                        h = h+2;
                    end
                    %                    for u = 1:2:size(filt_steps, 2)
                    %                        Rs(i,(u+1)/2) =  sqrt((filt_steps(i,u) - trans_orig_steps(i,u))^2+(filt_steps(i,u+1) - trans_orig_steps(i,u+1))^2);
                    %                        GoodArrow(trans_orig_steps(i,u),trans_orig_steps(i,u+1),filt_steps(i,u), filt_steps(i,u+1),ColScale(0,66,Rs(i,((u+1)/2))));
                    %                        hold on;
                    %                    end
                    frame = getframe(trackPlot.CurrentAxes);
                    writeVideo(v,frame);
                    trackPlot.delete;
                end
                close(v);
            end
            
            if app.MakeplotsCheckBox.Value == true
                filt_particle2d = zeros(app.nfile-1,size(filt_particle,3)*2);
                k = 1;
                for i = 1:2:(size(particle,3))*2
                    filt_particle2d(:,i) = filt_particle (:,1,k);
                    filt_particle2d(:,i+1) = filt_particle (:,2,k);
                    k = k+1;
                end
                figure(1);plot(filt_particle2d(:,1:2:end)); 
                title('X Displacement per Frame');
                ylabel('Displacement (px)');
                xlabel('Frame');
                 figure(2);plot(filt_particle2d(:,2:2:end));
                title('Y Displacement per Frame');
                ylabel('Displacement (px)');
                xlabel('Frame');
                figure(3); plot(filt_particle2d(:,1:2:end),filt_particle2d(:,2:2:end));
                title('X vs Y Displacement: Time History');
                ylabel('Y Displacement (px)');
                xlabel('X Displacement (px)');
                figure(3);axis equal;
                figure(4); plot(mean((filt_particle2d(:,2:2:end)-filt_particle2d(1,2:2:end))'))
                title('Mean Y Displacement per Frame');
                ylabel('Mean Displacement (px)');
                xlabel('Frame');
                figure(4);axis equal;
                figure(5); plot(mean((filt_particle2d(:,1:2:end)-filt_particle2d(1,1:2:end))'))
                title('Mean X Displacement per Frame');
                ylabel('Mean Displacement (px)');
                xlabel('Frame');
                figure(5);axis equal;
                
                saveas(figure(1),sprintf('%sXDisplacement.png',app.path));
                saveas(figure(2),sprintf('%sYDisplacement.png',app.path));
                saveas(figure(3),sprintf('%sXYTime.png',app.path));
                saveas(figure(4),sprintf('%sAverageY.png',app.path));
                saveas(figure(5),sprintf('%sAverageX.png',app.path));
                button = questdlg('The analysis is complete.', 'Attention', 'OK', 'Close all','Close all');
                if strcmp(button, 'Close all') 
                    close all;
                end
            end
        end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackingAnalysisWizardUIFigure = uifigure('Visible', 'off');
            app.TrackingAnalysisWizardUIFigure.Name = 'Tracking Analysis Wizard';
            app.LoadpreviousdisplacmentsButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.LoadpreviousdisplacmentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpreviousdisplacmentsButtonPushed, true);
            app.LoadpreviousdisplacmentsButton.Text = 'Load previous displacments';
            app.LoadedLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.LoadedLabel.Visible = 'off';
            app.LoadedLabel.Text = 'Loaded.';
            app.RemovepointsCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.RemovepointsCheckBox.Text = 'Remove points';
            app.IgnoretransformCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.IgnoretransformCheckBox.Text = 'Ignore transform';
            app.MakevideoCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.MakevideoCheckBox.Text = 'Make video';
            app.MakeplotsCheckBox = uicheckbox(app.TrackingAnalysisWizardUIFigure);
            app.MakeplotsCheckBox.Text = 'Make plots';
            app.AnalysisLabel = uilabel(app.TrackingAnalysisWizardUIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.RunAnalysisButton = uibutton(app.TrackingAnalysisWizardUIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            % Show the figure after all components are created
            app.TrackingAnalysisWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackingAnalysisWizardUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackingAnalysisWizardUIFigure)
        end
    end
end