classdef videostabilizeapp_real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoStabilizationWizardUIFigure  matlab.ui.Figure
        VideoStabilizerLabel    matlab.ui.control.Label
        RestpointeroveranoptionformoreinformationLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        FinalframeLabel         matlab.ui.control.Label
        fFrameLabel             matlab.ui.control.Label
        StartframeLabel         matlab.ui.control.Label
        sFrameLabel             matlab.ui.control.Label
        EditFOILabel            matlab.ui.control.Label
        ImageselectButton       matlab.ui.control.Button
        PTThreshLabel           matlab.ui.control.Label
        PTThreshEditField       matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel  matlab.ui.control.Label
        StepsizeEditField       matlab.ui.control.NumericEditField
        SelectaNewROIButton     matlab.ui.control.Button
        RundiagnosticsButton    matlab.ui.control.Button
        PreviewButton           matlab.ui.control.Button
        SavestackButton         matlab.ui.control.Button
        FrameSpinnerLabel       matlab.ui.control.Label
        FrameSpinner            matlab.ui.control.Spinner
        SetasstartframeButton   matlab.ui.control.Button
        SetasfinalframeButton   matlab.ui.control.Button
    end

    % Version 1.0 || 2019-06-13 || Ryan Dempsey & David Szonyi
    properties (Access = public)
     DialogApp;
     rectr; %rectangle object to be drawn corresponding to roi
     imgBr; %second image in stack
     getterprop; %window to draw roi
     a; %array of objects with info abt images
     mydir; %directory containing image stack
     path; %path to stack
     secondPath;
     filename; %name of first image in stack
     secondFilename; % last frame
     nfile; %size of stack
     startFrame; %first frame index
     endFrame; % last frame
     frameSpinnerStart; %value to start the frame preview scroller on
     frOI; % foi value
     cancel;
     
    end
    
    methods (Access = private)
        
        
        function printimage(app,filenum)
            %{
            prints the image to the preview pane. Frame shown
            corresponds to filenum argument. startFrame = first frame, imgA = displayed img,
            I is the fcn displaying imgA on the specified axes. 
            %}
            imgA = imread([app.path app.a(app.frameSpinnerStart+filenum).name]);
        app.UIAxes.Visible = 1; %make axes appear 
        I = imshow(imgA,'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
        app.UIAxes.XLim = [0 I.XData(2)]; % resizes image to fit frame
        app.UIAxes.YLim = [0 I.YData(2)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectaNewROIButton
        function SelectaNewROIButtonPushed(app, event)
     close all;     
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');


%%%% Get first file

if app.startFrame >= app.nfile
    errordlg('The current start frame is too deep into the folder.  Choose an earlier frame.', 'Invalid start frame');
    return;
end
    
    imgB = imread([app.path app.a(app.startFrame+1).name]); % Read second frame into imgB

    imgB = imflatfield(imgB,40); %flat-field correction w/ SD = 40

    app.getterprop = figure('Visible', 'off'); %opens figure w imgB
    
    app.imgBr = imgB; %sets imgB to property for later use
    
    oldRect = app.rectr;
    imshow(imgB);
    app.getterprop.Visible = 'off';
    app.rectr = drawrectangle;    %gets roi input
    if isvalid(app.rectr) == 1
    app.getterprop.Visible = 'on';
    end
    if isvalid(app.rectr) == 0
        warndlg('The ROI was not saved.', 'No new ROI');
        app.rectr = oldRect;
        return;
    end
    
%       set(app.getterprop, 'CloseRequestFcn', 'myCloseReq(app.DialogApp, 0, 0)');
      
    app.DialogApp = dialogapp(app,app.rectr); %opens second app with initial roi as argument
    
    
        end

        % Button pushed function: RundiagnosticsButton
        function RundiagnosticsButtonPushed(app, event)
%          set(0, 'DefaultFigureCloseRequestFcn', 'closereq');
            close all;
            clearvars -except filename path app;
            warning('off', 'Images:initSize:adjustingMag');

if  ~exist('roidata.mat','file')
 errordlg('Please select an ROI','Error: No selected ROI');%if there is no roidata user must make one
 return;
end

if app.startFrame == app.endFrame % if only one frame, kick back to start
    errordlg('First and final frame must be distinct.', 'Invalid start & end');
    return;
end
if app.startFrame > app.endFrame % if first frame comes after last, " " " "
    errordlg('Make sure the final frame comes after the first frame', 'Frames out of order');
    return;
end
if app.StepsizeEditField.Value < 1 % if step size is negative, " " " "
    errordlg('Invalid step size.  Must be a positive number.', 'Error: Invalid step size');
    return;
end

if app.StepsizeEditField.Value > 0.5*app.frOI % if the step size is too big, " " " " 
    errordlg('The chosen step size is too large for the number of frames chosen.  Try using more frames or reducing the step size.','Step size too large');
    return;
end
if app.PTThreshEditField.Value < 10 % if the sensitivity is really low, just double check that it was intentional
  result = uiconfirm(app.VideoStabilizationWizardUIFigure, 'Warning: Your PT threshold is very low.  Do you wish to continue?', 'Low PT Thresh');
  if strcmp('Cancel', result) == 1
      return;
  end
end


load('roidata.mat','rect'); %loads roi

if isvalid(rect) == 0%check that saved obj is valid. May not be if user closed ROI select window then hit 'keep'
    errordlg('ROI data is invalid or deleted.  Please select a new ROI.', 'Invalid/deleted ROI');
    return; 
end

roix = rect.Position(1); %gets position of roi from rectangle object
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);
            
%%%% Get first file

imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.StepsizeEditField.Value).name]); % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;      


   ptThresh = app.PTThreshEditField.Value;  %How sensitive the search is 1 = low, 1000 = high


%store roi characteristics and pt thresh         
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);         
            
      figOne = figure('Visible','off'); imshowpair(imgA, imgB, 'montage');
      title(['Frame A', repmat(' ',[1 70]), 'Frame B']);
      figOne.Visible = 'on'; 
%just now making visible to prevent attempted access at deleted fig obj't
%%
figTwo = figure('Visible', 'off'); imshowpair(imgA,imgB,'ColorChannels','red-cyan');
title('Color composite (frame A = red, frame B = cyan)');
figTwo.Visible = 'on';

% Display blobs found in images A and B.
figThree = figure('Visible', 'off'); imshow(imgA); hold on;
plot(pointsA);
title('Blobs in A');
figThree.Visible = 'on';

figFour = figure('Visible', 'off'); imshow(imgB); hold on;
plot(pointsB);
title('Blobs in B');
figFour.Visible = 'on';

%% Step 3. Select Correspondences Between Points
% Next we pick correspondences between the points derived above. For each
% point, we extract a Fast Retina Keypoint (FREAK) descriptor centered
% around it. The matching cost we use between points is the Hamming
% distance since FREAK descriptors are binary. Points in frame A and frame
% B are matched putatively. Note that there is no uniqueness constraint, so
% points from frame B can correspond to multiple points in frame A.

% Extract FREAK descriptors for the corners
[featuresA, pointsA] = extractFeatures(imgA, pointsA);
[featuresB, pointsB] = extractFeatures(imgB, pointsB);

%%
% Match features which were found in the current and the previous frames.
% Since the FREAK descriptors are binary, the |matchFeatures| function 
% uses the Hamming distance to find the corresponding points.
indexPairs = matchFeatures(featuresA, featuresB);
pointsA = pointsA(indexPairs(:, 1), :);
pointsB = pointsB(indexPairs(:, 2), :);

%%
% The image below shows the same color composite given above, but added are
% the points from frame A in red, and the points from frame B in green.
% Yellow lines are drawn between points to show the correspondences
% selected by the above procedure. Many of these correspondences are
% correct, but there is also a significant number of outliers.

figFive = figure('Visible', 'off'); showMatchedFeatures(imgA, imgB, pointsA, pointsB);
R1 = rectangle('Position',[roix roiy roiw roih], 'EdgeColor',[1 0 0]);
legend('A', 'B');
title('Matched blobs in ROI');
figFive.Visible = 'on';
%% Step 4. Estimating Transform from Noisy Correspondences
% Many of the point correspondences obtained in the previous step are
% incorrect. But we can still derive a robust estimate of the geometric
% transform between the two images using the M-estimator SAmple Consensus
% (MSAC) algorithm, which is a variant of the RANSAC algorithm. The MSAC
% algorithm is implemented in the |estimateGeometricTransform| function.
try
    [tform, pointsBm, pointsAm] = estimateGeometricTransform(pointsB, pointsA, 'similarity','Confidence',95,'MaxDistance',5);
catch
    close all;
    errordlg('Whoops! We''re having trouble finding blobs.  The current ROI may have been saved for a different image stack or frame.  Check your ROI.', 'Well, this is embarrassing...');  
    return; 
end
imgBp = imwarp(imgB, tform, 'OutputView', imref2d(size(imgB)));
pointsBmp = transformPointsForward(tform, pointsBm.Location);

%%
% Below is a color composite showing frame A overlaid with the reprojected
% frame B, along with the reprojected point correspondences. 

figSix = figure('Visible', 'off');
showMatchedFeatures(imgA, imgBp, pointsAm, pointsBmp);
legend('A', 'B');
title('Matched Points from A & B');
figSix.Visible = 'on';
%% Step 5. Transform Approximation and Smoothing
% Extract scale and rotation part sub-matrix.

H = tform.T;
R = H(1:2,1:2);
% Compute theta from mean of two possible arctangents
theta = mean([atan2(R(2),R(1)) atan2(-R(3),R(4))]);
% Compute scale from mean of two stable mean calculations
scale = mean(R([1 4])/cos(theta));
% Translation remains the same:
translation = H(3, 1:2);
% Reconstitute new s-R-t transform:
HsRt = [[scale*[cos(theta) -sin(theta); sin(theta) cos(theta)]; ...
  translation], [0 0 1]'];
tformsRT = affine2d(HsRt);

imgBold = imwarp(imgB, tform, 'OutputView', imref2d(size(imgB)));
imgBsRt = imwarp(imgB, tformsRT, 'OutputView', imref2d(size(imgB)));
figure(figTwo);
figTwo.Visible = 'off';
clf(figTwo); %using figTwo instead of figure(2) in case user closed one or more figure before more were made
imshowpair(imgBold,imgBsRt,'ColorChannels','red-cyan'), axis image;
title('Colour composite');
figTwo.Visible = 'on';

selection = questdlg('The diagnostic has finished.', 'Diagnostic complete!', 'Close all figures', 'OK', 'OK');
if strcmp(selection, 'Close all figures') == 1
    close all;
end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)

%     set(0, 'DefaultFigureCloseRequestFcn', 'closereq');        
close all;
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');


if app.startFrame == app.endFrame
    errordlg('First and final frame must be distinct.', 'Invalid start & end');
    return;
end
if app.startFrame > app.endFrame
    errordlg('Make sure the final frame comes after the first frame', 'Frames out of order');
    return;
end
if app.StepsizeEditField.Value < 0
    errordlg('Invalid step size.  Must be a positive number.', 'Error: Invalid step size');
    return;
end

if app.StepsizeEditField.Value > 0.5*app.frOI
    errordlg('The chosen step size is too large for the number of frames chosen.  Try using more frames or reducing the step size.','Step size too large');
    return;
end

if  ~exist('roidata.mat','file')
errordlg('Please select an ROI','Error: No selected ROI');%ensures user has roi data
return;
end  

 if app.PTThreshEditField.Value < 10
    result = uiconfirm(app.VideoStabilizationWizardUIFigure, 'Warning: Your PT threshold is very low.  Do you wish to continue?', 'Low PT Thresh');
    if strcmp('Cancel', result) == 1
      return;
    end
end                

 load('roidata.mat','rect');
 
if isvalid(rect) == 0%check that saved obj is valid. May not be if user closed ROI select window then hit 'keep'
    errordlg('ROI data is invalid or deleted.  Please select a new ROI.', 'Invalid/deleted ROI');
    return;
end
%get roi data and store in rect, and get position

roix = rect.Position(1);
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);

%%%% Get first file
%startFrame = find(strcmp({app.a.name}, app.filename)==1);

imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.StepsizeEditField.Value).name]); % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;


%%%THE SETTINGS ARE BELOW%%%


ptThresh = app.PTThreshEditField.Value;  %How sensitive the search is 1 = low, 1000 = high


%%%THE SETTINGS ARE ABOVE%%%


%probably unused for diagnostic
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
        

imgB = imread([app.path app.a(app.startFrame).name]); %read second frame
imgBp = imgB;
correctedMean = imgBp/app.frOI*app.StepsizeEditField.Value;
movMean = imgB/app.frOI*app.StepsizeEditField.Value;
% ii = 2;
ii = app.StepsizeEditField.Value;
Hcumulative = eye(3);
HsRts = [];
Hcumulatives = [];
while ii < app.frOI
    
    %%%Transform ROI
    tform = affine2d(Hcumulative);
    rectx = [roix;roix+roiw];
    recty = [roiy;roiy+roih];
    [U,V] = transformPointsInverse(tform,rectx,recty);
    troix = U(1);
    troiw = U(2)-troix;
    troiy = V(1);
    troih = V(2)-troiy;
    
    if troix <1 
        troix = 1;
    end
    if (troix+troiw)>size(imgA,2)
        troiw = size(imgA,2)-troix-1;
    end
%     if troiw <0
%         troiw = -troiw;
%     end
    if troiy <1 
        troiy = 1;
    end
    if (troiy+troih)>= size(imgA,1)
        troih = size(imgA,1)-troiy-1;
    end
    
    % Read in new frame
    imgA = imgB; % z^-1
    imgAp = imgBp; % z^-1
    imgB = imread([app.path app.a(app.startFrame+ii).name]);
    movMean = movMean + imgB/app.frOI*app.StepsizeEditField.Value;
    
    % Estimate transform from frame A to frame B, and fit as an s-R-t
     try 
            H = IRL_EstStabilizationTform(imgA,imgB,ptThresh,troix,troiy,troiw,troih);
    catch 
            errordlg('Oops! We''re having trouble finding blobs.  The current ROI may have been saved for a different image stack or frame.  Check your ROI.', 'Well, this is embarrassing...');
            return;
     end
    HsRt = IRL_TformToSRT(H);
    Hcumulative = HsRt * Hcumulative;
    HsRts= cat(3,HsRts,HsRt);
    Hcumulatives = cat(3,Hcumulatives,Hcumulative);
    imgBp = imwarp(imgB,affine2d(Hcumulative),'OutputView',imref2d(size(imgB)));
    
    if mod(ii,app.StepsizeEditField.Value*10)== 0;          %if not saving video, show images frame-by-frame
        figure(1);
        fused = imfuse(imgAp,imgBp,'blend');%,'ColorChannels','red-cyan'));
        imshow(fused);
%         imsho.w(imgA);
%         rectangle('Position',[troix troiy troiw troih], 'EdgeColor',[1 0 0]);
        titlestr = sprintf('File: ...%s\n',app.a(app.startFrame+ii).name((length(app.a(app.startFrame+ii).name)-8):length(app.a(app.startFrame+ii).name)));
        fprintf(titlestr)
    end

    correctedMean = correctedMean + imgBp/app.frOI;
    ii = ii+app.StepsizeEditField.Value;
    
end
stepSize = app.StepsizeEditField.Value;
startFrame = app.startFrame;
endFrame = app.endFrame;
save(sprintf('%stransform.mat',app.path),'Hcumulatives','HsRts', 'stepSize', 'startFrame', 'endFrame');

% Here you call the release method on the objects to close any open files
% and release memory.


%%
% During computation, we computed the mean of the raw video frames and of
% the corrected frames. These mean values are shown side-by-side below. The
% left image shows the mean of the raw input frames, proving that there was
% a great deal of distortion in the original video. The mean of the
% corrected frames on the right, however, shows the image core with almost
% no distortion. While foreground details have been blurred (as a necessary
% result of the car's forward motion), this shows the efficacy of the
% stabilization algorithm.

figure; imshowpair(movMean, correctedMean, 'montage');
title(['Raw input mean', repmat(' ',[1 50]), 'Corrected sequence mean']);

%% References
% [1] Tordoff, B; Murray, DW. "Guided sampling and consensus for motion
% estimation." European Conference n Computer Vision, 2002.
%
% [2] Lee, KY; Chuang, YY; Chen, BY; Ouhyoung, M. "Video Stabilization
% using Robust Feature Trajectories." National Taiwan University, 2009.
% 
% [3] Litvin, A; Konrad, J; Karl, WC. "Probabilistic video stabilization
% using Kalman filtering and mosaicking." IS&T/SPIE Symposium on Electronic
% Imaging, Image and Video Communications and Proc., 2003.
%
% [4] Matsushita, Y; Ofek, E; Tang, X; Shum, HY. "Full-frame Video
% Stabilization." Microsoft(R) Research Asia. CVPR 2005.

displayEndOfDemoMessage(app.filename)

       
        end

        % Button pushed function: SavestackButton
        function SavestackButtonPushed(app, event)
%             set(0, 'DefaultFigureCloseRequestFcn', 'closereq');
            close all;
clearvars -except filename path app a;
warning('off', 'Images:initSize:adjustingMag');
if app.startFrame == app.endFrame
    errordlg('First and final frame must be distinct.', 'Invalid start & end');
    return;
end
if app.startFrame > app.endFrame
    errordlg('Make sure the final frame comes after the first frame.', 'Frames out of order');
    return;
end
if app.StepsizeEditField.Value < 1
    errordlg('Invalid step size.  Must be a positive number.', 'Error: Invalid step size');
    return;
end

if app.StepsizeEditField.Value > 0.5*app.frOI
    errordlg('The chosen step size is too large for the number of frames chosen.  Try using more frames or reducing the step size.','Step size too large');
    return;
end

if  ~exist('roidata.mat','file')
    
errordlg('Please select an ROI','Error: No selected ROI');%ensures user has roi data
return;
end  

if app.PTThreshEditField.Value < 10
  result = uiconfirm(app.VideoStabilizationWizardUIFigure, 'Warning: Your PT threshold is very low.  Do you wish to continue?', 'Low PT Thresh');
  if strcmp('Cancel', result) == 1
      return;
  end
end
if app.PTThreshEditField.Value > 500
    result = uiconfirm(app.VideoStabilizationWizardUIFigure, 'Warning: Your PT threshold is very high.  Do you wish to continue?', 'High PT Thresh');
    if strcmp('Cancel', result) == 1
        return;
    end
end
  load('roidata.mat','rect'); %loads roi

if isvalid(rect) == 0 %check that saved obj is valid. May not be if user closed ROI select window then hit 'keep'
    errordlg('ROI data is invalid or deleted.  Please select a new ROI.', 'Invalid/deleted ROI');
    return;
end

roix = rect.Position(1); %gets position of roi from rectangle object
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);


%%%% Get first file


imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.StepsizeEditField.Value).name]); % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;


ptThresh = app.PTThreshEditField.Value; %gets values
% framesOI = app.FOIEditField.Value;


%probably unused here
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);


%% Step 6. Run on the Full Video
% Now we apply the above steps to smooth a video sequence. For readability,
% the above procedure of estimating the transform between two images has
% been placed in the MATLAB(R) function
% <matlab:edit(fullfile(matlabroot,'toolbox','vision','visiondemos','IRL_EstStabilizationTform.m')) |IRL_EstStabilizationTform|>.
% The function
% <matlab:edit(fullfile(matlabroot,'toolbox','vision','visiondemos','IRL_TformToSRT.m')) |IRL_TformToSRT|>
% also converts a general affine transform into a
% scale-rotation-translation transform.
%
% At each step we calculate the transform $H$ between the present frames.
% We fit this as an s-R-t transform, $H_{sRt}$. Then we combine this the
% cumulative transform, $H_{cumulative}$, which describes all camera motion
% since the first frame. The last two frames of the smoothed video are
% shown in a Video Player as a red-cyan composite.
%
% With this code, you can also take out the early exit condition to make
% the loop process the entire video.


imgB = imread([app.path app.a(app.startFrame).name]);
imgBp = imgB;
correctedMean = imgBp/app.frOI*app.StepsizeEditField.Value; 
movMean = imgB/app.frOI*app.StepsizeEditField.Value;
% ii = 2;
ii = app.StepsizeEditField.Value; %could we start at 0 to include the start frame?
Hcumulative = eye(3);
HsRts = [];
Hcumulatives = [];
counter = 0;
upnum = 0;
dwnnum = 0;

while ii < app.frOI
    if counter > 0
        if d.CancelRequested == true
            errordlg('The user cancelled the stack stabilization.', 'Stack not saved');
            return;
        end
    end
    
    %%%Transform ROI
    tform = affine2d(Hcumulative);
    rectx = [roix;roix+roiw];
    recty = [roiy;roiy+roih];
    [U,V] = transformPointsInverse(tform,rectx,recty);
    troix = U(1);
    troiw = U(2)-troix;
    troiy = V(1); 
    troih = V(2)-troiy;
    
    if troix <1 
        troix = 1;
    end
    if (troix+troiw)>size(imgA,2)
        troiw = size(imgA,2)-troix-1;
    end
%     if troiw <0
%         troiw = -troiw;
%     end
    if troiy <1 
        troiy = 1;
    end
    if (troiy+troih)>= size(imgA,1)
        troih = size(imgA,1)-troiy-1;
    end
    
    % Read in new frame
    imgA = imgB; % z^-1
    imgAp = imgBp; % z^-1
    imgB = imread([app.path app.a(app.startFrame+ii).name]);
    movMean = movMean + imgB/app.frOI*app.StepsizeEditField.Value;
    
    % Estimate transform from frame A to frame B, and fit as an s-R-t
    try 
            H = IRL_EstStabilizationTform(imgA,imgB,ptThresh,troix,troiy,troiw,troih);
    catch 
            errordlg('Oops! We''re having trouble finding blobs.  The current ROI may have been saved for a different image stack or frame.  Check your ROI.', 'Well, this is embarrassing...');
            return; %insufficient pts occurred for roi in low contrast areas
    end    % so that was likely the issue but good to dbl check whenever thrown
            HsRt = IRL_TformToSRT(H);
    [dwnnum, upnum] = checkRescale(H, dwnnum, upnum);%dumb function that returns whether a rescale was made.  Definitely a better way of making a public property inside Scott's fcn but whatever 
    Hcumulative = HsRt * Hcumulative;
    HsRts= cat(3,HsRts,HsRt);
    Hcumulatives = cat(3,Hcumulatives,Hcumulative);
    imgBp = imwarp(imgB,affine2d(Hcumulative),'OutputView',imref2d(size(imgB)));
    
    if counter == 0
        [file,rpath] = uiputfile('*');
        counter = 1;
        app.VideoStabilizationWizardUIFigure.Visible = 'off';
        app.VideoStabilizationWizardUIFigure.Visible = 'on';
        d = uiprogressdlg(app.VideoStabilizationWizardUIFigure, 'Title','Now Stabilizing...','Message','Stabilizing the selected frames.  Please wait...', 'Indeterminate','on', 'Cancelable','on');

    end
    
        savedir = [rpath, file, '\'];
        if rpath == 0
            errordlg('The file save was cancelled.', 'Data not saved');        
            return;
        end
       if ~exist(savedir,'dir')
           mkdir(savedir)
       end
        str = [savedir app.a(app.startFrame+ii).name((length(app.a(app.startFrame+ii).name)-8):length(app.a(app.startFrame+ii).name))];
        imwrite(imgBp,str);
    
    correctedMean = correctedMean + imgBp/app.frOI*app.StepsizeEditField.Value;
    ii = ii+app.StepsizeEditField.Value; %increment by frame alt'n #
    
end
stepSize = app.StepsizeEditField.Value;
startFrame = app.startFrame;
endFrame = app.endFrame;
save(sprintf('%stransform.mat',app.path),'Hcumulatives','HsRts', 'stepSize');
save(sprintf('%stransform.mat',savedir),'Hcumulatives','HsRts', 'stepSize', 'startFrame', 'endFrame', 'rect');

% Here you call the release method on the objects to close any open files
% and release memory.
close(d);


displayEndOfDemoMessage(app.filename)

    warndlg (['Video and Data Saved to ', rpath],'Attention');
    if dwnnum == 1 && upnum == 1
        newString = ['The program had to scale down ' int2str(dwnnum) ' time, and up ' int2str(upnum) ' time for a total of ' int2str(upnum + dwnnum) ' rescales.'];
    elseif dwnnum == 1 && upnum == 0
         newString = ['The program had to scale down ' int2str(dwnnum) ' time, and up ' int2str(upnum) ' times for a total of ' int2str(upnum + dwnnum) ' rescale.'];
    elseif dwnnum == 0 && upnum == 1
        newString = ['The program had to scale down ' int2str(dwnnum) ' times, and up ' int2str(upnum) ' time for a total of ' int2str(upnum + dwnnum) ' rescale.'];
    elseif dwnnum == 1 && upnum > 1
        newString = ['The program had to scale down ' int2str(dwnnum) ' time, and up ' int2str(upnum) ' times for a total of ' int2str(upnum + dwnnum) ' rescales.'];
    elseif dwnnum > 1 && upnum == 1
        newString = ['The program had to scale down ' int2str(dwnnum) ' times, and up ' int2str(upnum) ' time for a total of ' int2str(upnum + dwnnum) ' rescales.'];
    else
        newString = ['The program had to scale down ' int2str(dwnnum) ' times, and up ' int2str(upnum) ' times for a total of ' int2str(upnum + dwnnum) ' rescales.'];
    end
    warndlg(newString, 'Stabilization completed');

    clear path
        end

        % Button pushed function: ImageselectButton
        function ImageselectButtonPushed(app, event)
      
      
            close all;
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');

    
       oldFile = app.filename; %setting previous values to temp variables -- will explain
       oldPath = app.path;


    [app.filename, app.path] = uigetfile('*.tif', 'Select first image'); %gets first image and stores location
    if app.filename == 0
     errordlg('The user cancelled the image select.', 'No image loaded');
     app.filename = oldFile; %if the user cancels the image, the values are now 0
     app.path = oldPath; %this way, it will stay as whatever was selectly previously
     return;
    end
    if strncmp(fliplr(app.filename), 'fit.',4) == 0  %checks that the user did in fact load a tif (in case they picked "all files")
        errordlg('The first image was not a .tif file.  Select a .tif file.', 'Wrong file type'); %if when read backwards, the first 4 characters arent the correct backwards extension, the user is notified
        app.filename = oldFile; %same idea
        app.path = oldPath;
        return;
    end
    
    
        oldSecFile = app.secondFilename; %again, same idea is used
        oldSecPath = app.secondPath; 


    defaultName = fullfile(app.path, '*.tif'); %by default second image select starts in same folder as first
    [app.secondFilename, app.secondPath] = uigetfile(defaultName, 'Select last image');
    if app.secondFilename == 0
        errordlg('The user cancelled the image select.', 'No image loaded');
        app.secondFilename = oldSecFile;
        app.secondPath = oldSecPath;
        return;
    end
    if strncmp(fliplr(app.secondFilename), 'fit.', 4) == 0
        errordlg('The last image was not a .tif file.  Select a .tif file.', 'Wrong file type');
        app.secondFilename = oldSecFile;
        app.secondPath = oldSecPath;
        return;
    end
if strcmp(app.path, app.secondPath) == 0
    errordlg('The images must be in the same folder.', 'Invalid selection');
     app.secondFilename = oldSecFile;
     app.secondPath = oldSecPath;
    return;
end

app.mydir = app.path;
app.a = dir([app.path '*.tif']);
app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
app.endFrame = find(strcmp({app.a.name}, app.secondFilename) == 1);

if app.endFrame == app.startFrame
    errordlg('Select two different frames to start and end on.', 'Same frame selected');
    return;
end

if  app.endFrame - app.startFrame < 0 
    errordlg('Make sure the first image is loaded before the second, and has a lesser index.', 'Invalid selection');
    return;
end


 app.VideoStabilizationWizardUIFigure.Visible = 'off'; %this is just disappearing and reappearing the window
 app.VideoStabilizationWizardUIFigure.Visible = 'on'; %it is a workaround to the window appearing behind open windows on computer


app.frOI = app.endFrame - app.startFrame + 1;


app.nfile = size(app.a, 1); %- startFrame + 1; %take the number of files after selected image and store it in nfile property
[~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
app.a = app.a(reindex);

if app.nfile - app.startFrame < 1
    errordlg('Insufficient # of frames. Stabilize more than 3 frames of video.', 'Insufficient frames');
    return;
end
    

%enable all functions that are possible once image is loaded
app.StepsizeEditField.Enable  = true;
app.StepsizeEditFieldLabel.Enable = true;
app.StepsizeEditField.Limits = [1, app.frOI];
app.SelectaNewROIButton.Enable = true;
app.RundiagnosticsButton.Enable = true;
app.PreviewButton.Enable = true;
app.SavestackButton.Enable = true;
app.FrameSpinner.Visible = true;
app.FrameSpinnerLabel.Visible = true;
app.PTThreshEditField.Enable = true;
app.PTThreshLabel.Enable = true;
app.frameSpinnerStart = app.startFrame;
app.FrameSpinner.Value = app.startFrame;
app.FrameSpinner.Limits = [1,app.nfile]; 
app.FinalframeLabel.Enable = true;
app.SetasfinalframeButton.Enable = true;
app.SetasstartframeButton.Enable = true;
app.fFrameLabel.BackgroundColor = [0.80,0.80,0.80];
app.fFrameLabel.Text = int2str(app.endFrame);
app.StartframeLabel.Enable = true;
app.EditFOILabel.Visible = true;
app.sFrameLabel.Enable = true;
app.sFrameLabel.Text = int2str(app.startFrame);
app.sFrameLabel.BackgroundColor = [0.8 0.8 0.8];
app.printimage(0);
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            %{
            this fcn allows the user to view the selected image and scroll through the 
            stack by calling the previously defined fcn printimage
            %}
            value = app.FrameSpinner.Value;
            app.printimage (value-app.frameSpinnerStart);
        end

        % Button pushed function: SetasfinalframeButton
        function SetasfinalframeButtonPushed(app, event)
            %{
            this fcn allows the user to change the last
            frame to the one being previewed in the window
            %}
        app.endFrame = app.FrameSpinner.Value;
        app.fFrameLabel.Text = int2str(app.endFrame);
        app.frOI = app.endFrame - app.startFrame + 1;
        try
        app.StepsizeEditField.Limits = [1, 0.5*app.frOI];
        catch
            try
                app.StepsizeEditField.Limits = [1, app.frOI];
            catch
                app.StepsizeEditField.Value = 1;
                app.StepsizeEditField.Limits = [1,2];
            end
        end %else, error dialog will constrain it accordingly
            %when a button is pressed
        
        end

        % Button pushed function: SetasstartframeButton
        function SetasstartframeButtonPushed(app, event)
           
               % final frame button fcn, but for the starting frame
           
            app.startFrame = app.FrameSpinner.Value;
            app.sFrameLabel.Text = int2str(app.startFrame);
            app.frOI = app.endFrame - app.startFrame + 1;
              try
                app.StepsizeEditField.Limits = [1, 0.5*app.frOI];
              catch
                    try
                        app.StepsizeEditField.Limits = [1, app.frOI];
                    catch
                        app.StepsizeEditField.Value = 1;
                        app.StepsizeEditField.Limits = [1,2];
                     end
               end %else, error dialog will constrain it accordingly
            %when a button is pressed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoStabilizationWizardUIFigure = uifigure('Visible', 'off');
            app.VideoStabilizationWizardUIFigure.Name = 'Video Stabilization Wizard';
            app.VideoStabilizerLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.VideoStabilizerLabel.Text = 'Video Stabilizer';
            app.RestpointeroveranoptionformoreinformationLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.RestpointeroveranoptionformoreinformationLabel.Text = 'Rest pointer over an option for more information.';
            app.UIAxes = uiaxes(app.VideoStabilizationWizardUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.FinalframeLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.FinalframeLabel.Enable = 'off';
            app.FinalframeLabel.Text = 'Final frame:';
            app.fFrameLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.fFrameLabel.Text = '';
            app.StartframeLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.StartframeLabel.Enable = 'off';
            app.StartframeLabel.Text = 'Start frame: ';
            app.sFrameLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.sFrameLabel.Enable = 'off';
            app.sFrameLabel.Text = '';
            app.EditFOILabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.EditFOILabel.Visible = 'off';
            app.EditFOILabel.Text = 'Edit FOI:';
            app.ImageselectButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.ImageselectButton.ButtonPushedFcn = createCallbackFcn(app, @ImageselectButtonPushed, true);
            app.ImageselectButton.Tooltip = {'Load the first & last frame in the stack to be stabilized.  .tif files must be loaded preceding analysis.'};
            app.ImageselectButton.Text = 'Image select';
            app.PTThreshLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.PTThreshLabel.Enable = 'off';
            app.PTThreshLabel.Tooltip = {'Sensitivity scale from 0 to 1000.'};
            app.PTThreshLabel.Text = 'PT Thresh';
            app.PTThreshEditField = uieditfield(app.VideoStabilizationWizardUIFigure, 'numeric');
            app.PTThreshEditField.Limits = [0 1000];
            app.PTThreshEditField.Enable = 'off';
            app.PTThreshEditField.Tooltip = {'Point tracking threshold: sensitivity scale from 0 to 1000.  Typical values range between 150 - 500.  A higher number corresponds with finer tracking.'};
            app.StepsizeEditFieldLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.StepsizeEditFieldLabel.Enable = 'off';
            app.StepsizeEditFieldLabel.Tooltip = {'Allows analysis & stabilization of alternating frames starting on current frame.  A step size of 1 means every frame will be considered.  Decimals will be rounded.'};
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.VideoStabilizationWizardUIFigure, 'numeric');
            app.StepsizeEditField.RoundFractionalValues = 'on';
            app.StepsizeEditField.Enable = 'off';
            app.StepsizeEditField.Tooltip = {'Allows analysis & stabilization of alternating frames starting on current frame.  A step size of 1 means every frame will be considered.  Decimals will be rounded.'};
            app.SelectaNewROIButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SelectaNewROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaNewROIButtonPushed, true);
            app.SelectaNewROIButton.Enable = 'off';
            app.SelectaNewROIButton.Tooltip = {'Manually select a region of interest (region that will be stabilized).'};
            app.SelectaNewROIButton.Text = 'Select a New ROI';
            app.RundiagnosticsButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.RundiagnosticsButton.ButtonPushedFcn = createCallbackFcn(app, @RundiagnosticsButtonPushed, true);
            app.RundiagnosticsButton.Enable = 'off';
            app.RundiagnosticsButton.Tooltip = {'Provides a stabilization analysis based on the first 2 frames.'};
            app.RundiagnosticsButton.Text = 'Run diagnostics';
            app.PreviewButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Tooltip = {'Preview of the stabilized video every 10 frames.'};
            app.PreviewButton.Text = 'Preview';
            app.SavestackButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SavestackButton.ButtonPushedFcn = createCallbackFcn(app, @SavestackButtonPushed, true);
            app.SavestackButton.Enable = 'off';
            app.SavestackButton.Tooltip = {'Creates a folder containing the stabilized image stack and data where desired.'};
            app.SavestackButton.Text = 'Save stack';
            app.FrameSpinnerLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.FrameSpinnerLabel.Visible = 'off';
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VideoStabilizationWizardUIFigure);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Visible = 'off';
            app.FrameSpinner.Value = 1;
            app.SetasstartframeButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SetasstartframeButton.ButtonPushedFcn = createCallbackFcn(app, @SetasstartframeButtonPushed, true);
            app.SetasstartframeButton.Enable = 'off';
            app.SetasstartframeButton.Tooltip = {'Changes first frame to that currently shown.'};
            app.SetasstartframeButton.Text = 'Set as start frame';
            app.SetasfinalframeButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SetasfinalframeButton.ButtonPushedFcn = createCallbackFcn(app, @SetasfinalframeButtonPushed, true);
            app.SetasfinalframeButton.Enable = 'off';
            app.SetasfinalframeButton.Tooltip = {'Changes last frame to that currently shown.'};
            app.SetasfinalframeButton.Text = 'Set as final frame';
            % Show the figure after all components are created
            app.VideoStabilizationWizardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videostabilizeapp_real

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoStabilizationWizardUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoStabilizationWizardUIFigure)
        end
    end
end