classdef newTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticleTrackingWizardUIFigure  matlab.ui.Figure
        LoaddataButton                  matlab.ui.control.Button
        LoadstackButton                 matlab.ui.control.Button
        StarttrackingButton             matlab.ui.control.Button
        ROIsizeEditFieldLabel           matlab.ui.control.Label
        ROIsizeEditField                matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        NumberofpointsEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        filtSteps;
        stepsBK;
        stepPerPt;
        a;
        nfile;
        startFrame;
        filename;
        path;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            load DisplacementData.mat filt_steps steps_bk StepsPerPt;
            app.filtSteps = filt_steps;
            app.stepsBK = steps_bk;
            app.stepPerPt = StepsPerPt;
        end

        % Button pushed function: LoadstackButton
        function LoadstackButtonPushed(app, event)
            [app.filename, app.path]=uigetfile('*.tif');
            app.a = dir([app.path '*.tif']);
            app.nfile = max(size(app.a));    %% take the number of files and store it in nfile
            [~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
            app.a = app.a(reindex);
            app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
        end

        % Button pushed function: StarttrackingButton
        function StarttrackingButtonPushed(app, event)
            imgA = imread([app.path, app.filename]);
            ROISize = app.ROIsizeEditField.Value;
            numPoints = app.NumberofpointsEditField.Value;
            trackPts = SelectNPoints(imgA, ROISize, numPoints, 1);
            newUI = uifigure;
            d = uiprogressdlg(newUI, 'Title', 'Tracking your points. Please Wait...', 'Cancelable', 'on');
            steps = [];  
%             steps = zeros(length(numPoints), length(2*app.nfile));
%             steps = zeros(length(2*app.nfile), length(numPoints));
            for i = app.startFrame + 1 : app.nfile
                imgB = imread(sprintf('%s%s', app.path, app.a(i).name));
                imgB = imflatfield(imgB, 10);
                imgB = imsharpen(imgB);
                newPts = TrackNext(imgB, ROISize, trackPts);
                trackPts = newPts;
                steps = [steps, newPts];
                fraction = (i-app.startFrame)/(app.nfile-app.startFrame);
                d.Value = fraction;
                percent = fraction*100;
                d.Message = [num2str(round(percent, 1, 'decimals')), ' % complete.'];
                if d.CancelRequested
                    close(newUI);            
                    return;
                end
            end
            errordlg('test');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParticleTrackingWizardUIFigure = uifigure;
            app.ParticleTrackingWizardUIFigure.Name = 'Particle Tracking Wizard';
            app.LoaddataButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.LoadstackButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.LoadstackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstackButtonPushed, true);
            app.LoadstackButton.Text = 'Load stack';
            app.StarttrackingButton = uibutton(app.ParticleTrackingWizardUIFigure, 'push');
            app.StarttrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StarttrackingButtonPushed, true);
            app.StarttrackingButton.Text = 'Start tracking';
            app.ROIsizeEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.ROIsizeEditFieldLabel.Text = 'ROI size';
            app.ROIsizeEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
            app.NumberofpointsEditFieldLabel = uilabel(app.ParticleTrackingWizardUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.ParticleTrackingWizardUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = newTrackingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticleTrackingWizardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticleTrackingWizardUIFigure)
        end
    end
end