classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)


    rect = getrect;
    save('roidata.mat','rect');
roix = rect(1);
roiy = rect(2);
roiw = rect(3);
roih = rect(4);

pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end