classdef dialogapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfirmationUIFigure  matlab.ui.Figure
        RedoButton            matlab.ui.control.Button
        KeepButton            matlab.ui.control.Button
        KeepselectedROILabel  matlab.ui.control.Label
    end

    properties (Access = public)
     imgBr; %image for roi select, obtained from parent app
     CallingApp; %parent app
     recter; % roi, obtained from parent app
     buttonPressed; % boolean for whether a button was pressed
    end
    
    methods (Access = private)
        
%         function closeWin(app, src, callbackdata)
%             warndlg('The ROI was not saved.', 'User canceled ROI');
%             app.recter = rect;
%             delete(app.ConfirmationUIFigure);
%             delete(gcf);
%         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, firstapp, rectangle)
          app.CallingApp = firstapp; % defined in order to access its properties
          app.recter = rectangle; %obtained from input in parent app
          app.buttonPressed = 0; %initializing boolean
          rect = app.recter;
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
        rect = app.recter; % obtaining roi data from most recent input
        save('roidata.mat','rect');   
        app.CallingApp.getterprop.delete; 
            
           app.CallingApp.getterprop = figure(1); %redoing roi input
           imshow(app.CallingApp.imgBr);
           app.recter = drawrectangle;
          
        app.buttonPressed = 1; %button was pressed
        dialogapp(app.CallingApp,app.recter);%reopen dialog app to reconfirm new roi
        app.delete;%close previous dialog
           
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            rect = app.recter; %store roi data
            save('roidata.mat','rect');
            app.buttonPressed = 1; %button was pressed
            if isvalid(app.CallingApp.getterprop) == 1
            app.CallingApp.getterprop.delete; %close both windows and return to app
            end
            delete(app.ConfirmationUIFigure);
        end

        % Callback function: ConfirmationUIFigure, 
        % ConfirmationUIFigure
        function ConfirmationUIFigureCloseRequest(app, event)
            if app.buttonPressed == 0 %if neither button was pressed, then x was clicked
                warndlg('The ROI data was not saved.','User closed ROI');%warn user their data was not saved
            end
            if isvalid(app.CallingApp.getterprop)
                app.CallingApp.getterprop.delete; % close the roi select window
            end
            delete(app) %close app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfirmationUIFigure = uifigure;
            app.ConfirmationUIFigure.Name = 'Confirmation';
            app.ConfirmationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfirmationUIFigureCloseRequest, true);
            app.ConfirmationUIFigure.ButtonDownFcn = createCallbackFcn(app, @ConfirmationUIFigureCloseRequest, true);
            app.RedoButton = uibutton(app.ConfirmationUIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.KeepButton = uibutton(app.ConfirmationUIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            app.KeepselectedROILabel = uilabel(app.ConfirmationUIFigure);
            app.KeepselectedROILabel.Text = 'Keep selected ROI?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dialogapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfirmationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfirmationUIFigure)
        end
    end
end