classdef videostabilizeapp_real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoStabilizationWizardUIFigure  matlab.ui.Figure
        VideoStabilizerLabel    matlab.ui.control.Label
        RestpointeroveranoptionformoreinformationLabel  matlab.ui.control.Label
        PTThreshEditFieldLabel  matlab.ui.control.Label
        PTThreshEditField       matlab.ui.control.NumericEditField
        FOIEditFieldLabel       matlab.ui.control.Label
        FOIEditField            matlab.ui.control.NumericEditField
        SelectaNewROIButton     matlab.ui.control.Button
        RundiagnosticsButton    matlab.ui.control.Button
        PreviewButton           matlab.ui.control.Button
        SavevideoButton         matlab.ui.control.Button
        ImageselectButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        FrameSpinnerLabel       matlab.ui.control.Label
        FrameSpinner            matlab.ui.control.Spinner
        UseeveryEditFieldLabel  matlab.ui.control.Label
        UseeveryEditField       matlab.ui.control.NumericEditField
        framesLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        DialogApp
     rectr; %rectangle object to be drawn corresponding to roi
     imgBr; %second image in stack
     getterprop; %window to draw roi
     a; %array of objects with info abt images
     mydir; %directory containing image stack
     path; %path to stack
     filename; %name of stack file
     nfile; %size of stack
     startFrame
     frameSpinnerStart;
    end
    
    methods (Access = private)


        function results = printimage(app,filenum)
            %{
            prints the image to the preview pane. Frame shown
            corresponds to filenum argument. startFrame = first frame, imgA = displayed img,
            I is the fcn displaying imgA on the specified axes. 
            %}
        %startFrame = find(strcmp({app.a.name}, app.filename)==1);
        imgA = imread([app.path app.a(app.frameSpinnerStart+filenum).name]);
        app.UIAxes.Visible = 1;
        I = imshow(imgA,'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
        app.UIAxes.XLim = [0 I.XData(2)]; % resizes image to fit frame
        app.UIAxes.YLim = [0 I.YData(2)];
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectaNewROIButton
        function SelectaNewROIButtonPushed(app, event)
     close all;
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');


%%%% Get first file
%startFrame = find(strcmp({app.a.name}, app.filename)==1);


imgB = imread([app.path app.a(app.startFrame+1).name]); % Read second frame into imgB

imgB = imflatfield(imgB,40);

app.getterprop = figure(1); %opens figure w imgB
imshow(imgB);


app.imgBr = imgB; %sets imgB to property for later use


app.rectr = drawrectangle;    %gets roi input

app.DialogApp = dialogapp(app,app.rectr); %opens second app with initial roi as argument


        end

        % Button pushed function: RundiagnosticsButton
        function RundiagnosticsButtonPushed(app, event)
         close all;
            clearvars -except filename path app;
            warning('off', 'Images:initSize:adjustingMag');

if  ~exist('roidata.mat','file')    
errordlg('Please select an ROI','Error: No selected ROI');%if there is no roidata user must make one
 return;
end


%%%% Get first file
%startFrame = find(strcmp({app.a.name}, app.filename)==1);

imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.UseeveryEditField.Value).name]); %is this right? or should we still do +1? must ask Scott. % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;


            framesOI = app.FOIEditField.Value; %number of frames of interest -- unused in diagnostic
            ptThresh = app.PTThreshEditField.Value;  %How sensitive the search is 1 = low, 1000 = high


    load('roidata.mat','rect'); %loads roi


roix = rect.Position(1); %gets position of roi from rectangle object
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);


%store roi characteristics and pt thresh         
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);         
            
            figure; imshowpair(imgA, imgB, 'montage');
title(['Frame A', repmat(' ',[1 70]), 'Frame B']);

%%
figure; imshowpair(imgA,imgB,'ColorChannels','red-cyan');
title('Color composite (frame A = red, frame B = cyan)');

% Display blobs found in images A and B.
figure; imshow(imgA); hold on;
plot(pointsA);
title('Blobs in A');

figure; imshow(imgB); hold on;
plot(pointsB);
title('Blobs in B');

%% Step 3. Select Correspondences Between Points
% Next we pick correspondences between the points derived above. For each
% point, we extract a Fast Retina Keypoint (FREAK) descriptor centered
% around it. The matching cost we use between points is the Hamming
% distance since FREAK descriptors are binary. Points in frame A and frame
% B are matched putatively. Note that there is no uniqueness constraint, so
% points from frame B can correspond to multiple points in frame A.

% Extract FREAK descriptors for the corners
[featuresA, pointsA] = extractFeatures(imgA, pointsA);
[featuresB, pointsB] = extractFeatures(imgB, pointsB);

%%
% Match features which were found in the current and the previous frames.
% Since the FREAK descriptors are binary, the |matchFeatures| function 
% uses the Hamming distance to find the corresponding points.
indexPairs = matchFeatures(featuresA, featuresB);
pointsA = pointsA(indexPairs(:, 1), :);
pointsB = pointsB(indexPairs(:, 2), :);

%%
% The image below shows the same color composite given above, but added are
% the points from frame A in red, and the points from frame B in green.
% Yellow lines are drawn between points to show the correspondences
% selected by the above procedure. Many of these correspondences are
% correct, but there is also a significant number of outliers.

figure; showMatchedFeatures(imgA, imgB, pointsA, pointsB);
R1 = rectangle('Position',[roix roiy roiw roih], 'EdgeColor',[1 0 0]);
legend('A', 'B');

%% Step 4. Estimating Transform from Noisy Correspondences
% Many of the point correspondences obtained in the previous step are
% incorrect. But we can still derive a robust estimate of the geometric
% transform between the two images using the M-estimator SAmple Consensus
% (MSAC) algorithm, which is a variant of the RANSAC algorithm. The MSAC
% algorithm is implemented in the |estimateGeometricTransform| function.

[tform, pointsBm, pointsAm] = estimateGeometricTransform(pointsB, pointsA, 'similarity','Confidence',95,'MaxDistance',5);
imgBp = imwarp(imgB, tform, 'OutputView', imref2d(size(imgB)));
pointsBmp = transformPointsForward(tform, pointsBm.Location);

%%
% Below is a color composite showing frame A overlaid with the reprojected
% frame B, along with the reprojected point correspondences. 

figure;
showMatchedFeatures(imgA, imgBp, pointsAm, pointsBmp);
legend('A', 'B');

%% Step 5. Transform Approximation and Smoothing
% Extract scale and rotation part sub-matrix.

H = tform.T;
R = H(1:2,1:2);
% Compute theta from mean of two possible arctangents
theta = mean([atan2(R(2),R(1)) atan2(-R(3),R(4))]);
% Compute scale from mean of two stable mean calculations
scale = mean(R([1 4])/cos(theta));
% Translation remains the same:
translation = H(3, 1:2);
% Reconstitute new s-R-t transform:
HsRt = [[scale*[cos(theta) -sin(theta); sin(theta) cos(theta)]; ...
  translation], [0 0 1]'];
tformsRT = affine2d(HsRt);

imgBold = imwarp(imgB, tform, 'OutputView', imref2d(size(imgB)));
imgBsRt = imwarp(imgB, tformsRT, 'OutputView', imref2d(size(imgB)));

figure(2), clf;
imshowpair(imgBold,imgBsRt,'ColorChannels','red-cyan'), axis image;
title('Color composite of affine and s-R-t transform outputs');
 

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)

            
close all;
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');


if  ~exist('roidata.mat','file')
    
errordlg('Please select an ROI','Error: No selected ROI');%ensures user has roi data
return;
end  
                   

%%%% Get first file
%startFrame = find(strcmp({app.a.name}, app.filename)==1);

imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.UseeveryEditField.Value).name]);%again, should ask Scott his intentions here % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;


%%%THE SETTINGS ARE BELOW%%%


framesOI = app.FOIEditField.Value/app.UseeveryEditField.Value; %number of frames of interest
ptThresh = app.PTThreshEditField.Value;  %How sensitive the search is 1 = low, 1000 = high


%%%THE SETTINGS ARE ABOVE%%%

%get roi data and store in rect, and get position

    load('roidata.mat','rect');


roix = rect.Position(1);
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);

%probably unused for diagnostic
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
        

imgB = imread([app.path app.a(app.startFrame).name]);
imgBp = imgB;
correctedMean = imgBp/framesOI*app.UseeveryEditField.Value;
movMean = imgB/framesOI*app.UseeveryEditField.Value;
% ii = 2;
ii = app.UseeveryEditField.Value;
Hcumulative = eye(3);
HsRts = [];
Hcumulatives = [];
while ii < framesOI
    
    %%%Transform ROI
    tform = affine2d(Hcumulative);
    rectx = [roix;roix+roiw];
    recty = [roiy;roiy+roih];
    [U,V] = transformPointsInverse(tform,rectx,recty);
    troix = U(1);
    troiw = U(2)-troix;
    troiy = V(1);
    troih = V(2)-troiy;
    
    if troix <1 
        troix = 1;
    end
    if (troix+troiw)>size(imgA,2)
        troiw = size(imgA,2)-troix-1
    end
%     if troiw <0
%         troiw = -troiw;
%     end
    if troiy <1 
        troiy = 1;
    end
    if (troiy+troih)>= size(imgA,1)
        troih = size(imgA,1)-troiy-1
    end
    
    % Read in new frame
    imgA = imgB; % z^-1
    imgAp = imgBp; % z^-1
    imgB = imread([app.path app.a(app.startFrame+ii-1).name]);
    movMean = movMean + imgB/framesOI;
    
    % Estimate transform from frame A to frame B, and fit as an s-R-t
    H = IRL_EstStabilizationTform(imgA,imgB,ptThresh,troix,troiy,troiw,troih);
    HsRt = IRL_TformToSRT(H);
    Hcumulative = HsRt * Hcumulative;
    HsRts= cat(3,HsRts,HsRt);
    Hcumulatives = cat(3,Hcumulatives,Hcumulative);
    imgBp = imwarp(imgB,affine2d(Hcumulative),'OutputView',imref2d(size(imgB)));
    
    if mod(ii,10)==1           %if not saving video, show images frame-by-frame
        figure(1);
        fused = imfuse(imgAp,imgBp,'blend');%,'ColorChannels','red-cyan'));
        imshow(fused);
%         imshow(imgA);
%         rectangle('Position',[troix troiy troiw troih], 'EdgeColor',[1 0 0]);
        titlestr = sprintf('File: ...%s\n',app.a(app.startFrame+ii).name((length(app.a(app.startFrame+ii).name)-8):length(app.a(app.startFrame+ii).name)));
        fprintf(titlestr)
    end

    correctedMean = correctedMean + imgBp/framesOI;
    ii = ii+app.UseeveryEditField.Value;
    
end
save(sprintf('%stransform.mat',app.path),'Hcumulatives','HsRts');

% Here you call the release method on the objects to close any open files
% and release memory.


%%
% During computation, we computed the mean of the raw video frames and of
% the corrected frames. These mean values are shown side-by-side below. The
% left image shows the mean of the raw input frames, proving that there was
% a great deal of distortion in the original video. The mean of the
% corrected frames on the right, however, shows the image core with almost
% no distortion. While foreground details have been blurred (as a necessary
% result of the car's forward motion), this shows the efficacy of the
% stabilization algorithm.

figure; imshowpair(movMean, correctedMean, 'montage');
title(['Raw input mean', repmat(' ',[1 50]), 'Corrected sequence mean']);

%% References
% [1] Tordoff, B; Murray, DW. "Guided sampling and consensus for motion
% estimation." European Conference n Computer Vision, 2002.
%
% [2] Lee, KY; Chuang, YY; Chen, BY; Ouhyoung, M. "Video Stabilization
% using Robust Feature Trajectories." National Taiwan University, 2009.
% 
% [3] Litvin, A; Konrad, J; Karl, WC. "Probabilistic video stabilization
% using Kalman filtering and mosaicking." IS&T/SPIE Symposium on Electronic
% Imaging, Image and Video Communications and Proc., 2003.
%
% [4] Matsushita, Y; Ofek, E; Tang, X; Shum, HY. "Full-frame Video
% Stabilization." Microsoft(R) Research Asia. CVPR 2005.

displayEndOfDemoMessage(app.filename)

       
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            close all;
clearvars -except filename path app a;
warning('off', 'Images:initSize:adjustingMag');


if  ~exist('roidata.mat','file')
    
errordlg('Please select an ROI','Error: No selected ROI');%ensures user has roi data
return;
end  


%%%% Get first file
%startFrame = find(strcmp({app.a.name}, app.filename)==1);

imgA = imread([app.path app.a(app.startFrame).name]); % Read first frame into imgA
imgB = imread([app.path app.a(app.startFrame+app.UseeveryEditField.Value).name]); % Read second frame into imgB
imgA = imflatfield(imgA,40);
imgB = imflatfield(imgB,40);
leftI =imgA;       %this is here to copy into IRL_EstStabilizationTform.m
rightI =imgB;


ptThresh = app.PTThreshEditField.Value; %gets values
framesOI = app.FOIEditField.Value;


%store roi data in rect and store position data
    load('roidata.mat','rect');


roix = rect.Position(1);
roiy = rect.Position(2);
roiw = rect.Position(3);
roih = rect.Position(4);

%proably unused here
pointsA = detectSURFFeatures(leftI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);
pointsB = detectSURFFeatures(rightI, 'MetricThreshold', ptThresh,'ROI',[roix roiy roiw roih],'NumOctaves',3,'NumScaleLevels',6);


%% Step 6. Run on the Full Video
% Now we apply the above steps to smooth a video sequence. For readability,
% the above procedure of estimating the transform between two images has
% been placed in the MATLAB(R) function
% <matlab:edit(fullfile(matlabroot,'toolbox','vision','visiondemos','IRL_EstStabilizationTform.m')) |IRL_EstStabilizationTform|>.
% The function
% <matlab:edit(fullfile(matlabroot,'toolbox','vision','visiondemos','IRL_TformToSRT.m')) |IRL_TformToSRT|>
% also converts a general affine transform into a
% scale-rotation-translation transform.
%
% At each step we calculate the transform $H$ between the present frames.
% We fit this as an s-R-t transform, $H_{sRt}$. Then we combine this the
% cumulative transform, $H_{cumulative}$, which describes all camera motion
% since the first frame. The last two frames of the smoothed video are
% shown in a Video Player as a red-cyan composite.
%
% With this code, you can also take out the early exit condition to make
% the loop process the entire video.

imgB = imread([app.path app.a(app.startFrame).name]);
imgBp = imgB;
correctedMean = imgBp/framesOI*app.UseeveryEditField.Value; %may not work as intended - do we want all frames?
movMean = imgB/framesOI*app.UseeveryEditField.Value;
% ii = 2;
ii = app.UseeveryEditField.Value + 1;
Hcumulative = eye(3);
HsRts = [];
Hcumulatives = [];
counter = 0;
while ii < framesOI
    
    %%%Transform ROI
    tform = affine2d(Hcumulative);
    rectx = [roix;roix+roiw];
    recty = [roiy;roiy+roih];
    [U,V] = transformPointsInverse(tform,rectx,recty);
    troix = U(1);
    troiw = U(2)-troix;
    troiy = V(1);
    troih = V(2)-troiy;
    
    if troix <1 
        troix = 1;
    end
    if (troix+troiw)>size(imgA,2)
        troiw = size(imgA,2)-troix-1
    end
%     if troiw <0
%         troiw = -troiw;
%     end
    if troiy <1 
        troiy = 1;
    end
    if (troiy+troih)>= size(imgA,1)
        troih = size(imgA,1)-troiy-1
    end
    
    % Read in new frame
    imgA = imgB; % z^-1
    imgAp = imgBp; % z^-1
    imgB = imread([app.path app.a(app.startFrame+ii-1).name]);
    movMean = movMean + imgB/framesOI*app.UseeveryEditField.Value;
    
    % Estimate transform from frame A to frame B, and fit as an s-R-t
    H = IRL_EstStabilizationTform(imgA,imgB,ptThresh,troix,troiy,troiw,troih);
    HsRt = IRL_TformToSRT(H);
    Hcumulative = HsRt * Hcumulative;
    HsRts= cat(3,HsRts,HsRt);
    Hcumulatives = cat(3,Hcumulatives,Hcumulative);
    imgBp = imwarp(imgB,affine2d(Hcumulative),'OutputView',imref2d(size(imgB)));
    
    if counter == 0
        [file,rpath] = uiputfile('*');
        counter = 1;
    end
    
        savedir = [rpath, file, '/'];
        if rpath == 0
            errordlg('The file save was cancelled.', 'Video not saved');        
            return;
        end
       if ~exist(savedir,'dir')
           mkdir(savedir)
       end
        str = [savedir app.a(app.startFrame+ii-1).name((length(app.a(app.startFrame+ii).name)-8):length(app.a(app.startFrame+ii).name))];
        imwrite(imgBp,str);
    
    correctedMean = correctedMean + imgBp/framesOI*app.UseeveryEditField.Value;
    ii = ii+app.UseeveryEditField.Value;
    
end
save(sprintf('%stransform.mat',app.path),'Hcumulatives','HsRts');

% Here you call the release method on the objects to close any open files
% and release memory.


displayEndOfDemoMessage(app.filename)
% app.VideoStabilizationWizardUIFigure.Visible = 'off';
% app.VideoStabilizationWizardUIFigure.Visible = 'on';
warndlg (['Video Saved to ', rpath],'Attention');
    clear path


        end

        % Button pushed function: ImageselectButton
        function ImageselectButtonPushed(app, event)
                 close all;
clearvars -except filename path app;
warning('off', 'Images:initSize:adjustingMag');

    [app.filename, app.path]=uigetfile('*.tif'); %gets first image and stores location

app.VideoStabilizationWizardUIFigure.Visible = 'off'; %this is just disappearing and reappearing the window
app.VideoStabilizationWizardUIFigure.Visible = 'on'; %it is a workaround to the window appearing behind open windows on computer

if app.filename == 0 %if the user closes the window or hits cancel etc
    errordlg('Image select was cancelled.', 'No image loaded');
    return;
end


if strncmp(fliplr(app.filename), 'fit.',4) == 0 %checks that the user did in fact load a tif (in case they picked "all files")
    errordlg('Please select a .tif file.', '.tif not loaded'); %if when read backwards, the first 4 characters arent the correct backwards extension, the user is notified
    return;
end


app.mydir = app.path;
app.a = dir([app.path '*.tif']);
app.startFrame = find(strcmp({app.a.name}, app.filename)==1);
app.nfile = size(app.a, 1); %- startFrame + 1; %take the number of files after selected image and store it in nfile property
[~, reindex] = sort( str2double( regexp( {app.a.name}, '\d+', 'match', 'once' ))); %reorder to natural numbering
app.a = app.a(reindex);

if app.nfile - app.startFrame < 3
    errordlg('Insufficient # of frames. Stabilize more than 3 frames of video.', 'Insufficient frames');
    return;
end
    

%enable all functions that are possible once image is loaded
app.UseeveryEditField.Enable  = true;
app.UseeveryEditFieldLabel.Enable = true;
app.UseeveryEditField.Limits = [1, 5];
app.framesLabel.Enable = true;
app.SelectaNewROIButton.Enable = true;
app.RundiagnosticsButton.Enable = true;
app.PreviewButton.Enable = true;
app.SavevideoButton.Enable = true;
app.FrameSpinner.Visible = true;
app.FrameSpinnerLabel.Visible = true;
app.PTThreshEditField.Enable = true;
app.FOIEditField.Enable = true;
app.FOIEditFieldLabel.Enable = true;
app.PTThreshEditFieldLabel.Enable = true;
app.FOIEditField.Limits = [3,app.nfile-app.startFrame + 1];
app.frameSpinnerStart = app.startFrame;
app.FrameSpinner.Value = app.startFrame;
app.FrameSpinner.Limits = [1,app.nfile];
app.printimage(0);

        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            %{
            this fcn allows the user to view the selected image and scroll through the 
            stack by calling the previously defined fcn printimage
            %}
            value = app.FrameSpinner.Value;
            app.printimage (value-app.frameSpinnerStart);
            app.startFrame = value;
            if app.nfile-value > 3
            app.FOIEditField.Limits = [3,app.nfile-value + 1];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoStabilizationWizardUIFigure = uifigure;
            app.VideoStabilizationWizardUIFigure.Name = 'Video Stabilization Wizard';
            app.VideoStabilizerLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.VideoStabilizerLabel.Text = 'Video Stabilizer';
            app.RestpointeroveranoptionformoreinformationLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.RestpointeroveranoptionformoreinformationLabel.Text = 'Rest pointer over an option for more information.';
            app.PTThreshEditFieldLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.PTThreshEditFieldLabel.Enable = 'off';
            app.PTThreshEditFieldLabel.Tooltip = {'Sensitivity scale from 0 to 1000.'};
            app.PTThreshEditFieldLabel.Text = 'PT Thresh';
            app.PTThreshEditField = uieditfield(app.VideoStabilizationWizardUIFigure, 'numeric');
            app.PTThreshEditField.Limits = [0 1000];
            app.PTThreshEditField.Enable = 'off';
            app.PTThreshEditField.Tooltip = {'Sensitivity scale from 0 to 1000.'};
            app.FOIEditFieldLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.FOIEditFieldLabel.Enable = 'off';
            app.FOIEditFieldLabel.Tooltip = {'Frames of interest: number of images to be considered.'; '**note: if alternating images'; ' this is still the total number of images to be passed through.'};
            app.FOIEditFieldLabel.Text = 'FOI';
            app.FOIEditField = uieditfield(app.VideoStabilizationWizardUIFigure, 'numeric');
            app.FOIEditField.Limits = [0 Inf];
            app.FOIEditField.Enable = 'off';
            app.FOIEditField.Tooltip = {'Frames of interest: number of images to be considered.'; '**note: if alternating images'; ' this is still the total number of images to be passed through.'};
            app.SelectaNewROIButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SelectaNewROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaNewROIButtonPushed, true);
            app.SelectaNewROIButton.Enable = 'off';
            app.SelectaNewROIButton.Tooltip = {'Manually select a region of interest (region that will be stabilized).'};
            app.SelectaNewROIButton.Text = 'Select a New ROI';
            app.RundiagnosticsButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.RundiagnosticsButton.ButtonPushedFcn = createCallbackFcn(app, @RundiagnosticsButtonPushed, true);
            app.RundiagnosticsButton.Enable = 'off';
            app.RundiagnosticsButton.Tooltip = {'Provides a stabilization analysis based on the first 2 frames.'};
            app.RundiagnosticsButton.Text = 'Run diagnostics';
            app.PreviewButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Tooltip = {'Preview of the stabilized video every 10 frames.'};
            app.PreviewButton.Text = 'Preview';
            app.SavevideoButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Enable = 'off';
            app.SavevideoButton.Tooltip = {'Saves the stabilized video in the specified folder.'};
            app.SavevideoButton.Text = 'Save video';
            app.ImageselectButton = uibutton(app.VideoStabilizationWizardUIFigure, 'push');
            app.ImageselectButton.ButtonPushedFcn = createCallbackFcn(app, @ImageselectButtonPushed, true);
            app.ImageselectButton.Tooltip = {'Load the first image in the stack to be stabilized.  .tif file must be loaded preceding analysis.'};
            app.ImageselectButton.Text = 'Image select';
            app.UIAxes = uiaxes(app.VideoStabilizationWizardUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.FrameSpinnerLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.FrameSpinnerLabel.Visible = 'off';
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VideoStabilizationWizardUIFigure);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Visible = 'off';
            app.FrameSpinner.Value = 1;
            app.UseeveryEditFieldLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.UseeveryEditFieldLabel.Enable = 'off';
            app.UseeveryEditFieldLabel.Text = 'Use every';
            app.UseeveryEditField = uieditfield(app.VideoStabilizationWizardUIFigure, 'numeric');
            app.UseeveryEditField.Enable = 'off';
            app.UseeveryEditField.Tooltip = {'Allows analysis & stabilization of alternating frames starting on current frame.  A value of 1 means every frame will be considered.'};
            app.framesLabel = uilabel(app.VideoStabilizationWizardUIFigure);
            app.framesLabel.Enable = 'off';
            app.framesLabel.Tooltip = {'Allows analysis & stabilization of alternating frames starting on current frame.  A value of 1 means every frame will be considered.'};
            app.framesLabel.Text = 'frame(s).';
        end
    end

    methods (Access = public)

        % Construct app
        function app = videostabilizeapp_real

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoStabilizationWizardUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoStabilizationWizardUIFigure)
        end
    end
end